classdef App_20200802_app2_Tab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup2                    matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        UIAxes1                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes6                      matlab.ui.control.UIAxes
        UIAxes7                      matlab.ui.control.UIAxes
        UIAxes8                      matlab.ui.control.UIAxes
        GridLayout2                  matlab.ui.container.GridLayout
        Slider1                      matlab.ui.control.Slider
        Slider2                      matlab.ui.control.Slider
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout13                 matlab.ui.container.GridLayout
        LimMotionEditFieldLabel      matlab.ui.control.Label
        LimMotionEditField           matlab.ui.control.NumericEditField
        GridLayout16                 matlab.ui.container.GridLayout
        GridLayout17                 matlab.ui.container.GridLayout
        FlipCheckBox                 matlab.ui.control.CheckBox
        MACheckBox                   matlab.ui.control.CheckBox
        ButtonGroup                  matlab.ui.container.ButtonGroup
        BP1Button                    matlab.ui.control.RadioButton
        BP2Button                    matlab.ui.control.RadioButton
        RBCGAButton                  matlab.ui.control.RadioButton
        BCGDButton                   matlab.ui.control.RadioButton
        BCGAButton                   matlab.ui.control.RadioButton
        RBCGDButton                  matlab.ui.control.RadioButton
        TabGroup                     matlab.ui.container.TabGroup
        BP1Tab                       matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        Label                        matlab.ui.control.Label
        SPEndIntvEditFieldLabel      matlab.ui.control.Label
        SPEndIntvEditField           matlab.ui.control.NumericEditField
        SPMinHgtEditFieldLabel       matlab.ui.control.Label
        SPMinHgtEditField            matlab.ui.control.NumericEditField
        DPMaxHgtEditFieldLabel       matlab.ui.control.Label
        DPMaxHgtEditField            matlab.ui.control.NumericEditField
        Label2                       matlab.ui.control.Label
        Label3                       matlab.ui.control.Label
        SPStIntvEditField_2Label     matlab.ui.control.Label
        SPStIntvEditField            matlab.ui.control.NumericEditField
        Label4                       matlab.ui.control.Label
        BP2Tab                       matlab.ui.container.Tab
        GridLayout4_2                matlab.ui.container.GridLayout
        Label_2                      matlab.ui.control.Label
        SPEndIntvEditField_2Label    matlab.ui.control.Label
        SPEndIntvEditField_2         matlab.ui.control.NumericEditField
        SPMinHgtEditField_2Label     matlab.ui.control.Label
        SPMinHgtEditField_2          matlab.ui.control.NumericEditField
        DPMaxHgtEditField_2Label     matlab.ui.control.Label
        DPMaxHgtEditField_2          matlab.ui.control.NumericEditField
        Label2_2                     matlab.ui.control.Label
        Label3_2                     matlab.ui.control.Label
        SPStIntvEditFieldLabel       matlab.ui.control.Label
        SPStIntvEditField_2          matlab.ui.control.NumericEditField
        Label4_2                     matlab.ui.control.Label
        BCG_ATab                     matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        IMinPeakHgtEditFieldLabel    matlab.ui.control.Label
        IMinPeakHgtEditField         matlab.ui.control.NumericEditField
        Label31                      matlab.ui.control.Label
        JMinPeakHgtEditFieldLabel    matlab.ui.control.Label
        JMinPeakHgtEditField         matlab.ui.control.NumericEditField
        BtJEndEditFieldLabel         matlab.ui.control.Label
        BtJEndEditField              matlab.ui.control.NumericEditField
        KMinPeakHgtEditFieldLabel    matlab.ui.control.Label
        KMinPeakHgtEditField         matlab.ui.control.NumericEditField
        BtKEndEditFieldLabel         matlab.ui.control.Label
        BtKEndEditField              matlab.ui.control.NumericEditField
        JStartEditFieldLabel         matlab.ui.control.Label
        JStartEditField              matlab.ui.control.NumericEditField
        Label32                      matlab.ui.control.Label
        Label33                      matlab.ui.control.Label
        Label34                      matlab.ui.control.Label
        Label35                      matlab.ui.control.Label
        Label36                      matlab.ui.control.Label
        JMinPromEditFieldLabel       matlab.ui.control.Label
        JMinPromEditField            matlab.ui.control.NumericEditField
        IStartLabel                  matlab.ui.control.Label
        IStartEditField              matlab.ui.control.NumericEditField
        HStartEditFieldLabel         matlab.ui.control.Label
        HStartEditField              matlab.ui.control.NumericEditField
        HMinPeakHgtEditFieldLabel    matlab.ui.control.Label
        HMinPeakHgtEditField         matlab.ui.control.NumericEditField
        LMinPeakHgtEditFieldLabel    matlab.ui.control.Label
        LMinPeakHgtEditField         matlab.ui.control.NumericEditField
        LEndEditFieldLabel           matlab.ui.control.Label
        LEndEditField                matlab.ui.control.NumericEditField
        Label36_3                    matlab.ui.control.Label
        Label36_4                    matlab.ui.control.Label
        Label36_5                    matlab.ui.control.Label
        Label36_6                    matlab.ui.control.Label
        Label36_7                    matlab.ui.control.Label
        Label36_8                    matlab.ui.control.Label
        RBCG_ATab                    matlab.ui.container.Tab
        GridLayout5_2                matlab.ui.container.GridLayout
        IMinPeakHgtEditField_2Label  matlab.ui.control.Label
        IMinPeakHgtEditField_2       matlab.ui.control.NumericEditField
        Label41                      matlab.ui.control.Label
        JMinPeakHgtEditField_2Label  matlab.ui.control.Label
        JMinPeakHgtEditField_2       matlab.ui.control.NumericEditField
        BtJEndEditField_2Label       matlab.ui.control.Label
        BtJEndEditField_2            matlab.ui.control.NumericEditField
        KMinPeakHgtEditField_2Label  matlab.ui.control.Label
        KMinPeakHgtEditField_2       matlab.ui.control.NumericEditField
        BtKEndEditField_2Label       matlab.ui.control.Label
        BtKEndEditField_2            matlab.ui.control.NumericEditField
        JStartEditField_2Label       matlab.ui.control.Label
        JStartEditField_2            matlab.ui.control.NumericEditField
        Label42                      matlab.ui.control.Label
        Label43                      matlab.ui.control.Label
        Label44                      matlab.ui.control.Label
        Label45                      matlab.ui.control.Label
        Label46                      matlab.ui.control.Label
        JMinPromEditField_2Label     matlab.ui.control.Label
        JMinPromEditField_2          matlab.ui.control.NumericEditField
        IStartEditField_2Label       matlab.ui.control.Label
        IStartEditField_2            matlab.ui.control.NumericEditField
        HStartEditField_2Label       matlab.ui.control.Label
        HStartEditField_2            matlab.ui.control.NumericEditField
        HMinPeakHgtEditField_2Label  matlab.ui.control.Label
        HMinPeakHgtEditField_2       matlab.ui.control.NumericEditField
        LMinPeakHgtEditField_2Label  matlab.ui.control.Label
        LMinPeakHgtEditField_2       matlab.ui.control.NumericEditField
        LEndEditField_2Label         matlab.ui.control.Label
        LEndEditField_2              matlab.ui.control.NumericEditField
        Label46_3                    matlab.ui.control.Label
        Label46_4                    matlab.ui.control.Label
        Label46_5                    matlab.ui.control.Label
        Label46_6                    matlab.ui.control.Label
        Label46_7                    matlab.ui.control.Label
        Label46_8                    matlab.ui.control.Label
        BCG_DTab                     matlab.ui.container.Tab
        GridLayout5_3                matlab.ui.container.GridLayout
        IMinPeakHgtEditField_3Label  matlab.ui.control.Label
        IMinPeakHgtEditField_3       matlab.ui.control.NumericEditField
        Label31_2                    matlab.ui.control.Label
        JMinPeakHgtEditField_3Label  matlab.ui.control.Label
        JMinPeakHgtEditField_3       matlab.ui.control.NumericEditField
        BtJEndEditField_3Label       matlab.ui.control.Label
        BtJEndEditField_3            matlab.ui.control.NumericEditField
        KMinPeakHgtEditField_3Label  matlab.ui.control.Label
        KMinPeakHgtEditField_3       matlab.ui.control.NumericEditField
        BtKEndEditField_3Label       matlab.ui.control.Label
        BtKEndEditField_3            matlab.ui.control.NumericEditField
        JStartEditField_3Label       matlab.ui.control.Label
        JStartEditField_3            matlab.ui.control.NumericEditField
        Label32_2                    matlab.ui.control.Label
        Label33_2                    matlab.ui.control.Label
        Label34_2                    matlab.ui.control.Label
        Label35_2                    matlab.ui.control.Label
        Label36_2                    matlab.ui.control.Label
        JMinPromEditField_3Label     matlab.ui.control.Label
        JMinPromEditField_3          matlab.ui.control.NumericEditField
        IStartEditField_3Label       matlab.ui.control.Label
        IStartEditField_3            matlab.ui.control.NumericEditField
        HStartEditField_3Label       matlab.ui.control.Label
        HStartEditField_3            matlab.ui.control.NumericEditField
        HMinPeakHgtEditField_3Label  matlab.ui.control.Label
        HMinPeakHgtEditField_3       matlab.ui.control.NumericEditField
        LMinPeakHgtEditField_3Label  matlab.ui.control.Label
        LMinPeakHgtEditField_3       matlab.ui.control.NumericEditField
        LEndEditField_3Label         matlab.ui.control.Label
        LEndEditField_3              matlab.ui.control.NumericEditField
        Label36_9                    matlab.ui.control.Label
        Label36_10                   matlab.ui.control.Label
        Label36_11                   matlab.ui.control.Label
        Label36_12                   matlab.ui.control.Label
        Label36_13                   matlab.ui.control.Label
        Label36_14                   matlab.ui.control.Label
        RBCG_DTab                    matlab.ui.container.Tab
        GridLayout5_4                matlab.ui.container.GridLayout
        IMinPeakHgtEditField_4Label  matlab.ui.control.Label
        IMinPeakHgtEditField_4       matlab.ui.control.NumericEditField
        Label41_2                    matlab.ui.control.Label
        JMinPeakHgtEditField_4Label  matlab.ui.control.Label
        JMinPeakHgtEditField_4       matlab.ui.control.NumericEditField
        BtJEndEditField_4Label       matlab.ui.control.Label
        BtJEndEditField_4            matlab.ui.control.NumericEditField
        KMinPeakHgtEditField_4Label  matlab.ui.control.Label
        KMinPeakHgtEditField_4       matlab.ui.control.NumericEditField
        BtKEndEditField_4Label       matlab.ui.control.Label
        BtKEndEditField_4            matlab.ui.control.NumericEditField
        JStartEditField_4Label       matlab.ui.control.Label
        JStartEditField_4            matlab.ui.control.NumericEditField
        Label42_2                    matlab.ui.control.Label
        Label43_2                    matlab.ui.control.Label
        Label44_2                    matlab.ui.control.Label
        Label45_2                    matlab.ui.control.Label
        Label46_2                    matlab.ui.control.Label
        JMinPromEditField_4Label     matlab.ui.control.Label
        JMinPromEditField_4          matlab.ui.control.NumericEditField
        IStartEditField_4Label       matlab.ui.control.Label
        IStartEditField_4            matlab.ui.control.NumericEditField
        HStartEditField_4Label       matlab.ui.control.Label
        HStartEditField_4            matlab.ui.control.NumericEditField
        HMinPeakHgtEditField_4Label  matlab.ui.control.Label
        HMinPeakHgtEditField_4       matlab.ui.control.NumericEditField
        LMinPeakHgtEditField_4Label  matlab.ui.control.Label
        LMinPeakHgtEditField_4       matlab.ui.control.NumericEditField
        LEndEditField_4Label         matlab.ui.control.Label
        LEndEditField_4              matlab.ui.control.NumericEditField
        Label46_9                    matlab.ui.control.Label
        Label46_10                   matlab.ui.control.Label
        Label46_11                   matlab.ui.control.Label
        Label46_12                   matlab.ui.control.Label
        Label46_13                   matlab.ui.control.Label
        Label46_14                   matlab.ui.control.Label
        GridLayout6                  matlab.ui.container.GridLayout
        AllFeaturesButton            matlab.ui.control.Button
        MinMaxWinButton              matlab.ui.control.Button
        GridLayout7                  matlab.ui.container.GridLayout
        SaveButton                   matlab.ui.control.Button
        RemoveButton                 matlab.ui.control.Button
        WinFeatureButton             matlab.ui.control.Button
        GridLayout12                 matlab.ui.container.GridLayout
        GridLayout14                 matlab.ui.container.GridLayout
        HPfrEditFieldLabel           matlab.ui.control.Label
        HPfrEditField                matlab.ui.control.NumericEditField
        DefaultLabel                 matlab.ui.control.Label
        Label37                      matlab.ui.control.Label
        GridLayout15                 matlab.ui.container.GridLayout
        HPfrIntEditFieldLabel        matlab.ui.control.Label
        HPfrIntEditField             matlab.ui.control.NumericEditField
        DefaultLabel_2               matlab.ui.control.Label
        Label38                      matlab.ui.control.Label
        FeaturesTab                  matlab.ui.container.Tab
        GridLayout8                  matlab.ui.container.GridLayout
        UIAxes1_2                    matlab.ui.control.UIAxes
        UIAxes2_2                    matlab.ui.control.UIAxes
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes4_2                    matlab.ui.control.UIAxes
        UIAxes5_2                    matlab.ui.control.UIAxes
        UIAxes6_2                    matlab.ui.control.UIAxes
        UIAxes7_2                    matlab.ui.control.UIAxes
        UIAxes8_2                    matlab.ui.control.UIAxes
        GridLayout9                  matlab.ui.container.GridLayout
        MovWindowSpinnerLabel        matlab.ui.control.Label
        MovWindowSpinner             matlab.ui.control.Spinner
        GridLayout10                 matlab.ui.container.GridLayout
        MovAveButton                 matlab.ui.control.Button
        MinMaxWinButton_2            matlab.ui.control.Button
        PlotButton                   matlab.ui.control.Button
        GridLayout19                 matlab.ui.container.GridLayout
        Slider1_2                    matlab.ui.control.Slider
        Slider2_2                    matlab.ui.control.Slider
        RepTab                       matlab.ui.container.Tab
        GridLayout11                 matlab.ui.container.GridLayout
        UIAxes1_Rep                  matlab.ui.control.UIAxes
        UIAxes2_Rep                  matlab.ui.control.UIAxes
        UIAxes3_Rep                  matlab.ui.control.UIAxes
        UIAxes4_Rep                  matlab.ui.control.UIAxes
        UIAxes5_Rep                  matlab.ui.control.UIAxes
        UIAxes6_Rep                  matlab.ui.control.UIAxes
        GridLayout18                 matlab.ui.container.GridLayout
        PlotButton_2                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        axKeep % Description
        axKeepFeat
        Data
        slider1Val
        slider1_2Val
        Features
        FtHandle
        SigHandle
        CurrWinBeat
        CurrWinFeat
        FeatButtFlag
        WinNo
        RepHandle
        MAVal
        DirectoryPath
    end
    
    methods (Access = private)
        
        function results = Draw(app,flagStart)
            if flagStart == 1
                app.SigHandle.sig1 = plot(app.UIAxes1,app.Data.time,app.Data.ECGt_Adj);
                hold(app.UIAxes1,'on')
                plot(app.UIAxes1,app.Data.time(app.Data.Peakb(1:end-1)),app.Data.ECGt_Adj(app.Data.Peakb(1:end-1)),'og')
                app.SigHandle.sig2 = plot(app.UIAxes2,app.Data.time,app.Data.ECGb);
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2,app.Data.time(app.Data.Peakb),app.Data.ECGb(app.Data.Peakb),'o')
                L = length(app.Data.BP1);
                app.SigHandle.sig3 = plot(app.UIAxes3,app.Data.time(1:L),app.Data.BP1(1:L));
                app.SigHandle.sig4 = plot(app.UIAxes4,app.Data.time(1:L),app.Data.BP2(1:L));
            end
            app.SigHandle.sig5 = plot(app.UIAxes5,app.Data.time,app.Data.BCGKeep,'k');
            hold(app.UIAxes5,'on')
            app.SigHandle.sig9 = plot(app.UIAxes5,app.Data.time(app.Data.flagInd),app.Data.BCGKeep(app.Data.flagInd),'r.');
            app.SigHandle.sig10 = plot(app.UIAxes5,app.Data.time,app.Data.lim*ones(1,length(app.Data.time)),'g');
            app.SigHandle.sig6 = plot(app.UIAxes6,app.Data.time,app.Data.RotBCGKeep(:,1),'k');
            app.SigHandle.sig7 = plot(app.UIAxes7,app.Data.time,app.Data.BCGIntKeep,'k');
            app.SigHandle.sig8 = plot(app.UIAxes8,app.Data.time,app.Data.RotBCGIntKeep(:,1),'k');
            %Xtick
            app.UIAxes1.XTick = app.Data.time(app.Data.Peakb);
            app.UIAxes2.XTick = app.Data.time(app.Data.Peakb);
            app.UIAxes3.XTick = app.Data.time(app.Data.Peakb);
            app.UIAxes4.XTick = app.Data.time(app.Data.Peakb);
            app.UIAxes5.XTick = app.Data.time(app.Data.Peakb);
            app.UIAxes6.XTick = app.Data.time(app.Data.Peakb);
            app.UIAxes7.XTick = app.Data.time(app.Data.Peakb);
            app.UIAxes8.XTick = app.Data.time(app.Data.Peakb);
            app.UIAxes2.XTickLabel = {''};
            app.UIAxes3.XTickLabel = {''};
            app.UIAxes4.XTickLabel = {''};
            app.UIAxes5.XTickLabel = {''};
            app.UIAxes6.XTickLabel = {''};
            app.UIAxes7.XTickLabel = {''};
            app.UIAxes8.XTickLabel = {''};
            % Grid
            app.UIAxes1.XGrid = 'on';app.UIAxes1.XMinorGrid = 'on';
            app.UIAxes2.XGrid = 'on';app.UIAxes2.XMinorGrid = 'on';
            app.UIAxes3.XGrid = 'on';app.UIAxes3.XMinorGrid = 'on';
            app.UIAxes4.XGrid = 'on';app.UIAxes4.XMinorGrid = 'on';
            app.UIAxes5.XGrid = 'on';app.UIAxes5.XMinorGrid = 'on';
            app.UIAxes6.XGrid = 'on';app.UIAxes6.XMinorGrid = 'on';
            app.UIAxes7.XGrid = 'on';app.UIAxes7.XMinorGrid = 'on';
            app.UIAxes8.XGrid = 'on';app.UIAxes8.XMinorGrid = 'on';
            title(app.UIAxes1, app.Data.Event)
        end
        
        
        function CurrWindowBeat(app)
            fs = app.Data.fs;
            app.CurrWinBeat = find(and(app.Data.PeakExc<app.UIAxes1.XLim(2)*fs,app.Data.PeakExc>app.UIAxes1.XLim(1)*fs));
        end
        
        function CurrWindowFeat(app)
            fs = app.Data.fs;
            app.CurrWinFeat = find(and(app.Data.PeakExc<app.UIAxes1_2.XLim(2)*fs,app.Data.PeakExc>app.UIAxes1_2.XLim(1)*fs));
        end
        
        function FindCurrWinFeat(app,signalName)
            switch(signalName)
                case('BP1')
                        [app.Features.BP1,app.Features.plotBeat] = findFeatures(app.Features.BP1,app.Data.BP1,'BP',app.Data.param.BP1,app.Data.PeakExc,app.Data.fs,app.CurrWinBeat(1),app.CurrWinBeat(end));
                case('BP2')
                        [app.Features.BP2,app.Features.plotBeat] = findFeatures(app.Features.BP2,app.Data.BP2,'BP',app.Data.param.BP2,app.Data.PeakExc,app.Data.fs,app.CurrWinBeat(1),app.CurrWinBeat(end));
                case('BCGA')
                        [app.Features.BCG_A,app.Features.plotBeat] = findFeatures(app.Features.BCG_A,app.Data.BCGKeep,'BCG',app.Data.param.BCG_A,app.Data.PeakExc,app.Data.fs,app.CurrWinBeat(1),app.CurrWinBeat(end));
                case('RBCGA')
                        [app.Features.RBCG_A,app.Features.plotBeat] = findFeatures(app.Features.RBCG_A,app.Data.RotBCGKeep(:,1),'BCG',app.Data.param.RBCG_A,app.Data.PeakExc,app.Data.fs,app.CurrWinBeat(1),app.CurrWinBeat(end));
                case('BCGD')
                        [app.Features.BCG_D,app.Features.plotBeat] = findFeatures(app.Features.BCG_D,app.Data.BCGIntKeep,'BCG',app.Data.param.BCG_D,app.Data.PeakExc,app.Data.fs,app.CurrWinBeat(1),app.CurrWinBeat(end));
                case('RBCGD')
                        [app.Features.RBCG_D,app.Features.plotBeat] = findFeatures(app.Features.RBCG_D,app.Data.RotBCGIntKeep(:,1),'BCG',app.Data.param.RBCG_D,app.Data.PeakExc,app.Data.fs,app.CurrWinBeat(1),app.CurrWinBeat(end));
            end
        end
        
        function results = DrawUpdated(app,signalName)
            plotBeat = app.Features.plotBeat;
            switch(signalName)
                case('BP1')
                    delete(app.FtHandle.h1)
                    delete(app.FtHandle.h2)
                    delete(app.FtHandle.h5)
                    delete(app.FtHandle.h6)
                    app.FtHandle.h1 = plot(app.UIAxes3,app.Data.time(app.Features.BP1.SP(plotBeat,2)),app.Features.BP1.SP(plotBeat,1),'or');
                    app.FtHandle.h2 = plot(app.UIAxes3,app.Data.time(app.Features.BP1.DP(plotBeat,2)),app.Features.BP1.DP(plotBeat,1),'og');
                    app.FtHandle.h5 = plot(app.UIAxes3,app.Data.time(app.Features.BP1.tanSP(plotBeat,2)),app.Features.BP1.tanSP(plotBeat,1),'*r');
                    app.FtHandle.h6 = plot(app.UIAxes3,app.Data.time(app.Features.BP1.tanDP(plotBeat,2)),app.Features.BP1.tanDP(plotBeat,1),'*g');
                case('BP2')
                    delete(app.FtHandle.h3)
                    delete(app.FtHandle.h4)
                    delete(app.FtHandle.h7)
                    delete(app.FtHandle.h8)
                    app.FtHandle.h3 = plot(app.UIAxes4,app.Data.time(app.Features.BP2.SP(plotBeat,2)),app.Features.BP2.SP(plotBeat,1),'or');
                    app.FtHandle.h4 = plot(app.UIAxes4,app.Data.time(app.Features.BP2.DP(plotBeat,2)),app.Features.BP2.DP(plotBeat,1),'og');
                    app.FtHandle.h7 = plot(app.UIAxes4,app.Data.time(app.Features.BP2.tanSP(plotBeat,2)),app.Features.BP2.tanSP(plotBeat,1),'*r');
                    app.FtHandle.h8 = plot(app.UIAxes4,app.Data.time(app.Features.BP2.tanDP(plotBeat,2)),app.Features.BP2.tanDP(plotBeat,1),'*g');
                case('BCGA')
                    delete(app.FtHandle.bcg1)
                    delete(app.FtHandle.bcg2)
                    delete(app.FtHandle.bcg3)
                    delete(app.FtHandle.bcg13)
                    delete(app.FtHandle.bcg14)
                    app.FtHandle.bcg1 = plot(app.UIAxes5,app.Data.time(app.Features.BCG_A.J(plotBeat,2)),app.Features.BCG_A.J(plotBeat,1),'ob');
                    app.FtHandle.bcg2 = plot(app.UIAxes5,app.Data.time(app.Features.BCG_A.I(plotBeat,2)),app.Features.BCG_A.I(plotBeat,1),'or');
                    app.FtHandle.bcg3 = plot(app.UIAxes5,app.Data.time(app.Features.BCG_A.K(plotBeat,2)),app.Features.BCG_A.K(plotBeat,1),'og');
                    app.FtHandle.bcg13 = plot(app.UIAxes5,app.Data.time(app.Features.BCG_A.H(plotBeat,2)),app.Features.BCG_A.H(plotBeat,1),'oc');
                    app.FtHandle.bcg14 = plot(app.UIAxes5,app.Data.time(app.Features.BCG_A.L(plotBeat,2)),app.Features.BCG_A.L(plotBeat,1),'om');
                case('RBCGA')
                    delete(app.FtHandle.bcg4)
                    delete(app.FtHandle.bcg5)
                    delete(app.FtHandle.bcg6)
                    delete(app.FtHandle.bcg15)
                    delete(app.FtHandle.bcg16)
                    app.FtHandle.bcg4 = plot(app.UIAxes6,app.Data.time(app.Features.RBCG_A.J(plotBeat,2)),app.Features.RBCG_A.J(plotBeat,1),'ob');
                    app.FtHandle.bcg5 = plot(app.UIAxes6,app.Data.time(app.Features.RBCG_A.I(plotBeat,2)),app.Features.RBCG_A.I(plotBeat,1),'or');
                    app.FtHandle.bcg6 = plot(app.UIAxes6,app.Data.time(app.Features.RBCG_A.K(plotBeat,2)),app.Features.RBCG_A.K(plotBeat,1),'og');
                    app.FtHandle.bcg15 = plot(app.UIAxes6,app.Data.time(app.Features.RBCG_A.H(plotBeat,2)),app.Features.RBCG_A.H(plotBeat,1),'oc');
                    app.FtHandle.bcg16 = plot(app.UIAxes6,app.Data.time(app.Features.RBCG_A.L(plotBeat,2)),app.Features.RBCG_A.L(plotBeat,1),'om');
                case('BCGD')
                    delete(app.FtHandle.bcg7)
                    delete(app.FtHandle.bcg8)
                    delete(app.FtHandle.bcg9)
                    delete(app.FtHandle.bcg17)
                    delete(app.FtHandle.bcg18)
                    app.FtHandle.bcg7 = plot(app.UIAxes7,app.Data.time(app.Features.BCG_D.J(plotBeat,2)),app.Features.BCG_D.J(plotBeat,1),'ob');
                    app.FtHandle.bcg8 = plot(app.UIAxes7,app.Data.time(app.Features.BCG_D.I(plotBeat,2)),app.Features.BCG_D.I(plotBeat,1),'or');
                    app.FtHandle.bcg9 = plot(app.UIAxes7,app.Data.time(app.Features.BCG_D.K(plotBeat,2)),app.Features.BCG_D.K(plotBeat,1),'og');
                    app.FtHandle.bcg17 = plot(app.UIAxes7,app.Data.time(app.Features.BCG_D.H(plotBeat,2)),app.Features.BCG_D.H(plotBeat,1),'oc');
                    app.FtHandle.bcg18 = plot(app.UIAxes7,app.Data.time(app.Features.BCG_D.L(plotBeat,2)),app.Features.BCG_D.L(plotBeat,1),'om');
                case('RBCGD')
                    delete(app.FtHandle.bcg10)
                    delete(app.FtHandle.bcg11)
                    delete(app.FtHandle.bcg12)
                    delete(app.FtHandle.bcg19)
                    delete(app.FtHandle.bcg20)
                    app.FtHandle.bcg10 = plot(app.UIAxes8,app.Data.time(app.Features.RBCG_D.J(plotBeat,2)),app.Features.RBCG_D.J(plotBeat,1),'ob');
                    app.FtHandle.bcg11 = plot(app.UIAxes8,app.Data.time(app.Features.RBCG_D.I(plotBeat,2)),app.Features.RBCG_D.I(plotBeat,1),'or');
                    app.FtHandle.bcg12 = plot(app.UIAxes8,app.Data.time(app.Features.RBCG_D.K(plotBeat,2)),app.Features.RBCG_D.K(plotBeat,1),'og');
                    app.FtHandle.bcg19 = plot(app.UIAxes8,app.Data.time(app.Features.RBCG_D.H(plotBeat,2)),app.Features.RBCG_D.H(plotBeat,1),'oc');
                    app.FtHandle.bcg20 = plot(app.UIAxes8,app.Data.time(app.Features.RBCG_D.L(plotBeat,2)),app.Features.RBCG_D.L(plotBeat,1),'om');
            end
            
        end
        
        function results = FeatLabel(app)
            % Cut off
            app.Label37.Text = num2str(app.Data.param.CutOff);
            app.Label38.Text = num2str(app.Data.param.CutOffInt);
            app.HPfrEditField.Value = app.Data.param.CutOff;
            app.HPfrIntEditField.Value = app.Data.param.CutOffInt;
            % BP1
            app.Label.Text = num2str(app.Data.param.BP1.SPEnd/app.Data.fs*100);
            app.Label2.Text = [num2str(app.Data.param.BP1.SPMinPeakHeight),' [mmHg]'];
            app.Label3.Text = [num2str(-app.Data.param.BP1.DPMinPeakHeight),' [mmHg]'];
            app.Label4.Text = num2str(app.Data.param.BP1.SPSt/app.Data.fs*100);
            % BP2
            app.Label_2.Text = num2str(app.Data.param.BP2.SPEnd/app.Data.fs*100);
            app.Label2_2.Text = [num2str(app.Data.param.BP2.SPMinPeakHeight),' [mmHg]'];
            app.Label3_2.Text = [num2str(-app.Data.param.BP2.DPMinPeakHeight),' [mmHg]'];
            app.Label4_2.Text = num2str(app.Data.param.BP2.SPSt/app.Data.fs*100);
            % BCGA
            app.Label31.Text = num2str(app.Data.param.BCG_A.IMinPeakHeight);
            app.Label32.Text = num2str(app.Data.param.BCG_A.JMinPeakHeight);
            app.Label33.Text = num2str(app.Data.param.BCG_A.JEnd/app.Data.fs*100);
            app.Label34.Text = num2str(app.Data.param.BCG_A.KMinPeakHeight);
            app.Label35.Text = num2str(app.Data.param.BCG_A.KEnd/app.Data.fs*100);
            app.Label36.Text = num2str(app.Data.param.BCG_A.JSt/app.Data.fs*100);
            app.Label36_3.Text = num2str(app.Data.param.BCG_A.JProm);
            app.Label36_4.Text = num2str(app.Data.param.BCG_A.ISt/app.Data.fs*100);
            app.Label36_5.Text = num2str(app.Data.param.BCG_A.HSt/app.Data.fs*100);
            app.Label36_6.Text = num2str(app.Data.param.BCG_A.HMinPeakHeight);
            app.Label36_7.Text = num2str(app.Data.param.BCG_A.LMinPeakHeight);
            app.Label36_8.Text = num2str(app.Data.param.BCG_A.LEnd/app.Data.fs*100);
            % RBCGA
            app.Label41.Text = num2str(app.Data.param.RBCG_A.IMinPeakHeight);
            app.Label42.Text = num2str(app.Data.param.RBCG_A.JMinPeakHeight);
            app.Label43.Text = num2str(app.Data.param.RBCG_A.JEnd/app.Data.fs*100);
            app.Label44.Text = num2str(app.Data.param.RBCG_A.KMinPeakHeight);
            app.Label45.Text = num2str(app.Data.param.RBCG_A.KEnd/app.Data.fs*100);
            app.Label46.Text = num2str(app.Data.param.RBCG_A.JSt/app.Data.fs*100);
            app.Label46_3.Text = num2str(app.Data.param.RBCG_A.JProm);
            app.Label46_4.Text = num2str(app.Data.param.RBCG_A.ISt/app.Data.fs*100);
            app.Label46_5.Text = num2str(app.Data.param.RBCG_A.HSt/app.Data.fs*100);
            app.Label46_6.Text = num2str(app.Data.param.RBCG_A.HMinPeakHeight);
            app.Label46_7.Text = num2str(app.Data.param.RBCG_A.LMinPeakHeight);
            app.Label46_8.Text = num2str(app.Data.param.RBCG_A.LEnd/app.Data.fs*100);
            % BCGD
            app.Label31_2.Text = num2str(app.Data.param.BCG_D.IMinPeakHeight);
            app.Label32_2.Text = num2str(app.Data.param.BCG_D.JMinPeakHeight);
            app.Label33_2.Text = num2str(app.Data.param.BCG_D.JEnd/app.Data.fs*100);
            app.Label34_2.Text = num2str(app.Data.param.BCG_D.KMinPeakHeight);
            app.Label35_2.Text = num2str(app.Data.param.BCG_D.KEnd/app.Data.fs*100);
            app.Label36_2.Text = num2str(app.Data.param.BCG_D.JSt/app.Data.fs*100);
            app.Label36_9.Text = num2str(app.Data.param.BCG_D.JProm);
            app.Label36_10.Text = num2str(app.Data.param.BCG_D.ISt/app.Data.fs*100);
            app.Label36_11.Text = num2str(app.Data.param.BCG_D.HSt/app.Data.fs*100);
            app.Label36_12.Text = num2str(app.Data.param.BCG_D.HMinPeakHeight);
            app.Label36_13.Text = num2str(app.Data.param.BCG_D.LMinPeakHeight);
            app.Label36_14.Text = num2str(app.Data.param.BCG_D.LEnd/app.Data.fs*100);
            % RBCGD
            app.Label41_2.Text = num2str(app.Data.param.RBCG_D.IMinPeakHeight);
            app.Label42_2.Text = num2str(app.Data.param.RBCG_D.JMinPeakHeight);
            app.Label43_2.Text = num2str(app.Data.param.RBCG_D.JEnd/app.Data.fs*100);
            app.Label44_2.Text = num2str(app.Data.param.RBCG_D.KMinPeakHeight);
            app.Label45_2.Text = num2str(app.Data.param.RBCG_D.KEnd/app.Data.fs*100);
            app.Label46_2.Text = num2str(app.Data.param.RBCG_D.JSt/app.Data.fs*100);
            app.Label46_9.Text = num2str(app.Data.param.RBCG_D.JProm);
            app.Label46_10.Text = num2str(app.Data.param.RBCG_D.ISt/app.Data.fs*100);
            app.Label46_11.Text = num2str(app.Data.param.RBCG_D.HSt/app.Data.fs*100);
            app.Label46_12.Text = num2str(app.Data.param.RBCG_D.HMinPeakHeight);
            app.Label46_13.Text = num2str(app.Data.param.RBCG_D.LMinPeakHeight);
            app.Label46_14.Text = num2str(app.Data.param.RBCG_D.LEnd/app.Data.fs*100);
        end
        
        function DrawFeatTab2(app)
            if isfield(app.FtHandle,'tab2')
                for ii = setdiff(2:35,23:27)
                delete(app.FtHandle.(['tab',num2str(ii)]))
                end
            end
            % ECG
            app.FtHandle.tab2 = plot(app.UIAxes2_2,app.Data.time(app.Data.Peakb(1:end-1)),diff(app.Data.PeakExc)/app.Data.fs,'o');
            % BP1
            app.FtHandle.tab3 = plot(app.UIAxes3_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BP1.SP(:,1),'or');
            hold(app.UIAxes3_2,'on')
            app.FtHandle.tab4 = plot(app.UIAxes3_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BP1.DP(:,1),'og');
            app.FtHandle.tab5 = plot(app.UIAxes3_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BP1.tanDP(:,1),'*g');
            app.FtHandle.tab6 = plot(app.UIAxes3_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BP1.tanSP(:,1),'*r');
            % BP2
            app.FtHandle.tab7 = plot(app.UIAxes4_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BP2.SP(:,1),'or');
            hold(app.UIAxes4_2,'on')
            app.FtHandle.tab8 = plot(app.UIAxes4_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BP2.DP(:,1),'og');
            app.FtHandle.tab9 = plot(app.UIAxes4_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BP2.tanDP(:,1),'*g');
            app.FtHandle.tab10 = plot(app.UIAxes4_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BP2.tanSP(:,1),'*r');
            % BCG_A
            app.FtHandle.tab11 = plot(app.UIAxes5_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BCG_A.I(:,1),'or');
            hold(app.UIAxes5_2,'on')
            app.FtHandle.tab12 = plot(app.UIAxes5_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BCG_A.J(:,1),'ob');
            app.FtHandle.tab13 = plot(app.UIAxes5_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BCG_A.K(:,1),'og');
            app.FtHandle.tab28 = plot(app.UIAxes5_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BCG_A.H(:,1),'oc');
            app.FtHandle.tab29 = plot(app.UIAxes5_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BCG_A.L(:,1),'om');
            % Rotated BCG_A
            app.FtHandle.tab14 = plot(app.UIAxes6_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.RBCG_A.I(:,1),'or');
            hold(app.UIAxes6_2,'on')
            app.FtHandle.tab15 = plot(app.UIAxes6_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.RBCG_A.J(:,1),'ob');
            app.FtHandle.tab16 = plot(app.UIAxes6_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.RBCG_A.K(:,1),'og');
            app.FtHandle.tab30 = plot(app.UIAxes6_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.RBCG_A.H(:,1),'oc');
            app.FtHandle.tab31 = plot(app.UIAxes6_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.RBCG_A.L(:,1),'om');
            % BCG_D
            app.FtHandle.tab17 = plot(app.UIAxes7_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BCG_D.I(:,1),'or');
            hold(app.UIAxes7_2,'on')
            app.FtHandle.tab18 = plot(app.UIAxes7_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BCG_D.J(:,1),'ob');
            app.FtHandle.tab19 = plot(app.UIAxes7_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BCG_D.K(:,1),'og');
            app.FtHandle.tab32 = plot(app.UIAxes7_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BCG_D.H(:,1),'oc');
            app.FtHandle.tab33 = plot(app.UIAxes7_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.BCG_D.L(:,1),'om');
            % Rotated BCG_D
            app.FtHandle.tab20 = plot(app.UIAxes8_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.RBCG_D.I(:,1),'or');
            hold(app.UIAxes8_2,'on')
            app.FtHandle.tab21 = plot(app.UIAxes8_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.RBCG_D.J(:,1),'ob');
            app.FtHandle.tab22 = plot(app.UIAxes8_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.RBCG_D.K(:,1),'og');
            app.FtHandle.tab34 = plot(app.UIAxes8_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.RBCG_D.H(:,1),'oc');
            app.FtHandle.tab35 = plot(app.UIAxes8_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.RBCG_D.L(:,1),'om');
        end
        
        function DrawMovAv(app)
            if isfield(app.FtHandle,'tab23')
                delete(app.FtHandle.tab23)
                delete(app.FtHandle.tab24)
                delete(app.FtHandle.tab25)
                delete(app.FtHandle.tab26)
                delete(app.FtHandle.tab27)
            end
            app.FtHandle.tab23 = plot(app.UIAxes3_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.MovAv.BP1.tanSP,'-.b');
            app.FtHandle.tab24 = plot(app.UIAxes3_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.MovAv.BP1.tanDP,'-.k');
            app.FtHandle.tab25 = plot(app.UIAxes4_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.MovAv.BP2.SP,'-.b');
            app.FtHandle.tab26 = plot(app.UIAxes4_2,app.Data.time(app.Data.Peakb(1:end-1)),app.Features.MovAv.BP2.DP,'-.k');
            app.FtHandle.tab27 = plot(app.UIAxes3_2,app.Data.time(app.Data.Peakb(app.Features.MinMaxLoc)),app.Features.MovAv.BP1.tanSP(app.Features.MinMaxLoc),'*k','MarkerSize',8);
            % Xlim
            axLim = [440 500];
            app.UIAxes1_2.XLim = axLim;
            app.UIAxes2_2.XLim = axLim;
            app.UIAxes3_2.XLim = axLim;
            app.UIAxes4_2.XLim = axLim;
            app.UIAxes5_2.XLim = axLim;
            app.UIAxes6_2.XLim = axLim;
            app.UIAxes7_2.XLim = axLim;
            app.UIAxes8_2.XLim = axLim;
        end
        
        function results = DrawTabRep(app)
            if isfield(app.RepHandle,'rep1')
                delete(app.RepHandle.rep1); delete(app.RepHandle.rep2)
                delete(app.RepHandle.rep3); delete(app.RepHandle.rep4)
                delete(app.RepHandle.rep5); delete(app.RepHandle.rep6)
            end
            app.RepHandle.rep1 = plot(app.UIAxes1_Rep,app.Features.BP1Rep);
            app.RepHandle.rep2 = plot(app.UIAxes2_Rep,app.Features.BP2Rep);
            app.RepHandle.rep3 = plot(app.UIAxes3_Rep,app.Features.BCGRep);
            app.RepHandle.rep4 = plot(app.UIAxes4_Rep,app.Features.rotBCGRep);
            app.RepHandle.rep5 = plot(app.UIAxes5_Rep,app.Features.BCGIntRep);
            app.RepHandle.rep6 = plot(app.UIAxes6_Rep,app.Features.rotBCGIntRep);
        end
        
        function results = Flip(app)
               app.Data.AX1  = -app.Data.AX1;
               app.Data.AXFiltered1 = -app.Data.AXFiltered1;
               app.Data.BCGInt1 = -app.Data.BCGInt1;
               app.Data.BCG1 = -app.Data.BCG1;
               app.Data.MatAcc(:,[1,2]) = -app.Data.MatAcc(:,[1,2]);
               app.Data.MatFilt(:,[1,2]) = -app.Data.MatFilt(:,[1,2]);
               app.Data.MatBCG(:,[1,2]) = -app.Data.MatBCG(:,[1,2]);
               app.Data.MatBCGInt(:,[1,2]) = -app.Data.MatBCGInt(:,[1,2]);   
        end
        
        function results = Preprocess(app,flagAss)
            flagVal = app.LimMotionEditField.Value;
            [temp1, temp2, app.Data] = ProcessBCG(app.Data.AX1,app.Data,app.MACheckBox.Value,app.Data.param.CutOff,app.Data.param.CutOffInt,flagVal,flagAss);                        
            flagAss = 0;
            app.Data.BCGKeep = temp1; app.Data.BCGIntKeep = temp2;
            clear temp1 temp2
            % Rotated
            [sigRotated1, sigRotated2] = RotationAngle(app.Data);  % First 3 columns BCG1, second three columns BCG2
            if app.Data.accel == 1
                app.Data.sigRotated = sigRotated1';
            else
                app.Data.sigRotated = sigRotated2';
            end
            [temp1, temp2, app.Data] = ProcessBCG(app.Data.sigRotated,app.Data,app.MACheckBox.Value,app.Data.param.CutOff,app.Data.param.CutOffInt, flagVal,flagAss);                        
            app.Data.RotBCGKeep = temp1; app.Data.RotBCGIntKeep = temp2;
            clear temp1 temp2
        end
        
        function results = ClearFig(app)
            % Clear Signals
            delete(app.SigHandle.sig5)
            delete(app.SigHandle.sig6)
            delete(app.SigHandle.sig7)
            delete(app.SigHandle.sig8)
            delete(app.SigHandle.sig9)
            delete(app.SigHandle.sig10)
            % Clear Features
            if isfield(app.FtHandle,'h1')
                for ii = 1:8
                    delete(app.FtHandle.(['h',num2str(ii)]))
                end
            end
            if isfield(app.FtHandle,'bcg1')
                for ii = 1:20
                    delete(app.FtHandle.(['bcg',num2str(ii)]))
                end
            end
        end
        
        function results = EvalBCGInit(app)
            % Initial BCG evaluation
            flagAss = 1; flagVal = app.LimMotionEditField.Value;
            [app.Data.BCG1, app.Data.BCGInt1, app.Data] = ProcessBCG(app.Data.AX1,app.Data,0,app.Data.param.CutOff,app.Data.param.CutOffInt,flagVal,flagAss);
            % Rotated
            [sigRotated1, sigRotated2] = RotationAngle(app.Data);  % First 3 columns BCG1, second three columns BCG2
            if app.Data.accel == 1
                app.Data.sigRotated = sigRotated1';
            else
                app.Data.sigRotated = sigRotated2';
            end
            flagAss = 0;
            [temp1, temp2, app.Data] = ProcessBCG(app.Data.sigRotated,app.Data,0,app.Data.param.CutOff,app.Data.param.CutOffInt,flagVal,flagAss);
            app.Data.RotBCG1 = temp1; app.Data.RotBCGInt1 = temp2;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             uiopen
%             app.Data = data;
            app.Data =  evalin('base','data');
            app.DirectoryPath = evalin('base','DirectoryPath');
            app.Data.peakAssess = AssessFeat('ECG',app.Data.Peakb,app.Data.Peakb,app.Data.fs); 
            app.Data = EvalParam(app.Data);
            Flip(app)
            EvalBCGInit(app)
            Preprocess(app,0)
            flagStart = 1;
            Draw(app,flagStart)
            CurrWindowBeat(app)
            FeatLabel(app)
%             SaveButtonPushed(app)
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            axLim = 1.1*app.Data.time(end)*[value-10 value]/100;
            if ~isempty(app.slider1Val)
                axLim = [axLim(1) axLim(1)+(100-app.slider1Val)/100*(axLim(2)-axLim(1))];            
            end
            if value>=0
            app.UIAxes1.XLim = axLim;
            app.UIAxes2.XLim = axLim;
            app.UIAxes3.XLim = axLim;
            app.UIAxes4.XLim = axLim;
            app.UIAxes5.XLim = axLim;
            app.UIAxes6.XLim = axLim;
            app.UIAxes7.XLim = axLim;
            app.UIAxes8.XLim = axLim;
            app.axKeep = app.UIAxes1.XLim;
            end         
            CurrWindowBeat(app)
        end

        % Value changed function: Slider1
        function Slider1ValueChanged(app, event)
            value = app.Slider1.Value;
            axCurr(1) = app.axKeep(1);
            axCurr(2) = app.axKeep(1)+app.Data.time(end)/10;
            app.slider1Val = value;
            axLim = [axCurr(1) axCurr(1)+(100-value)/100*(axCurr(2)-axCurr(1))];
            axLimMin = [axCurr(1) axCurr(1)+0.05*(axCurr(2)-axCurr(1))];
            if value<99
            app.UIAxes1.XLim = axLim;
            app.UIAxes2.XLim = axLim;
            app.UIAxes3.XLim = axLim;
            app.UIAxes4.XLim = axLim;
            app.UIAxes5.XLim = axLim;
            app.UIAxes6.XLim = axLim;
            app.UIAxes7.XLim = axLim;
            app.UIAxes8.XLim = axLim;
            else
                app.UIAxes1.XLim = axLimMin;
                app.UIAxes2.XLim = axLimMin;
                app.UIAxes3.XLim = axLimMin;
                app.UIAxes4.XLim = axLimMin;
                app.UIAxes5.XLim = axLimMin;
                app.UIAxes6.XLim = axLimMin;
                app.UIAxes7.XLim = axLimMin;
                app.UIAxes8.XLim = axLimMin;
            end
            CurrWindowBeat(app)
        end

        % Callback function
        function OpenDataButtonPushed(app, event)
            uiopen
            app.Data = data;
            Draw(app,1)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            DrawFeatTab2(app)
        end

        % Button pushed function: AllFeaturesButton
        function AllFeaturesButtonPushed(app, event)
            flag = isempty(app.FeatButtFlag);
            if ~isfield(app.Features,'BP1')
                app.Features.BP1 = [];
                app.Features.BP2 = [];
                app.Features.BCG_A = [];
                app.Features.RBCG_A = [];
                app.Features.BCG_D = [];
                app.Features.RBCG_D = [];
            end
            % BP1
            if or(flag,and(~flag,app.BP1Button.Value))
            [app.Features.BP1,plotBeat] = findFeatures(app.Features.BP1,app.Data.BP1,'BP',app.Data.param.BP1,app.Data.PeakExc,app.Data.fs,1,length(app.Data.PeakExc));
            hold(app.UIAxes3,'on')
            if ~isempty(app.FtHandle)
                if ~isempty(app.FtHandle.h1)
                    delete(app.FtHandle.h1)
                    delete(app.FtHandle.h2)
                    delete(app.FtHandle.h5)
                    delete(app.FtHandle.h6)
                    
                end
            end
            app.FtHandle.h1 = plot(app.UIAxes3,app.Data.time(app.Features.BP1.SP(plotBeat,2)),app.Features.BP1.SP(plotBeat,1),'or');
            app.FtHandle.h2 = plot(app.UIAxes3,app.Data.time(app.Features.BP1.DP(plotBeat,2)),app.Features.BP1.DP(plotBeat,1),'og');
            app.FtHandle.h5 = plot(app.UIAxes3,app.Data.time(app.Features.BP1.tanSP(plotBeat,2)),app.Features.BP1.tanSP(plotBeat,1),'*r');
            app.FtHandle.h6 = plot(app.UIAxes3,app.Data.time(app.Features.BP1.tanDP(plotBeat,2)),app.Features.BP1.tanDP(plotBeat,1),'*g');
            end
            % BP2
            if or(flag,and(~flag,app.BP2Button.Value))
            [app.Features.BP2,plotBeat] = findFeatures(app.Features.BP2,app.Data.BP2,'BP',app.Data.param.BP2,app.Data.PeakExc,app.Data.fs,1,length(app.Data.PeakExc));
            hold(app.UIAxes4,'on')
            if isfield(app.FtHandle,'h3')
                    delete(app.FtHandle.h3)
                    delete(app.FtHandle.h4)
                    delete(app.FtHandle.h7)
                    delete(app.FtHandle.h8)
            end
            app.FtHandle.h3 = plot(app.UIAxes4,app.Data.time(app.Features.BP2.SP(plotBeat,2)),app.Features.BP2.SP(plotBeat,1),'or');
            app.FtHandle.h4 = plot(app.UIAxes4,app.Data.time(app.Features.BP2.DP(plotBeat,2)),app.Features.BP2.DP(plotBeat,1),'og');
            app.FtHandle.h7 = plot(app.UIAxes4,app.Data.time(app.Features.BP2.tanSP(plotBeat,2)),app.Features.BP2.tanSP(plotBeat,1),'*r');
            app.FtHandle.h8 = plot(app.UIAxes4,app.Data.time(app.Features.BP2.tanDP(plotBeat,2)),app.Features.BP2.tanDP(plotBeat,1),'*g');
            end
            % BCG_A
            if or(flag,and(~flag,app.BCGAButton.Value))
            [app.Features.BCG_A,plotBeat] = findFeatures(app.Features.BCG_A,app.Data.BCGKeep,'BCG',app.Data.param.BCG_A,app.Data.PeakExc,app.Data.fs,1,length(app.Data.PeakExc));
            hold(app.UIAxes5,'on')
            if isfield(app.FtHandle,'bcg1')
                    delete(app.FtHandle.bcg1)
                    delete(app.FtHandle.bcg2)
                    delete(app.FtHandle.bcg3)
                    delete(app.FtHandle.bcg13)
                    delete(app.FtHandle.bcg14)
            end
            app.FtHandle.bcg1 = plot(app.UIAxes5,app.Data.time(app.Features.BCG_A.J(plotBeat,2)),app.Features.BCG_A.J(plotBeat,1),'ob');
            app.FtHandle.bcg2 = plot(app.UIAxes5,app.Data.time(app.Features.BCG_A.I(plotBeat,2)),app.Features.BCG_A.I(plotBeat,1),'or');
            app.FtHandle.bcg3 = plot(app.UIAxes5,app.Data.time(app.Features.BCG_A.K(plotBeat,2)),app.Features.BCG_A.K(plotBeat,1),'og');
            app.FtHandle.bcg13 = plot(app.UIAxes5,app.Data.time(app.Features.BCG_A.H(plotBeat,2)),app.Features.BCG_A.H(plotBeat,1),'oc');
            app.FtHandle.bcg14 = plot(app.UIAxes5,app.Data.time(app.Features.BCG_A.L(plotBeat,2)),app.Features.BCG_A.L(plotBeat,1),'om');
            end
            % Rotated BCG_A
            if or(flag,and(~flag,app.RBCGAButton.Value))
            [app.Features.RBCG_A,plotBeat] = findFeatures(app.Features.RBCG_A,app.Data.RotBCGKeep(:,1),'BCG',app.Data.param.RBCG_A,app.Data.PeakExc,app.Data.fs,1,length(app.Data.PeakExc));
            hold(app.UIAxes6,'on')
            if isfield(app.FtHandle,'bcg4')
                    delete(app.FtHandle.bcg4)
                    delete(app.FtHandle.bcg5)
                    delete(app.FtHandle.bcg6)
                    delete(app.FtHandle.bcg15)
                    delete(app.FtHandle.bcg16)
            end
            app.FtHandle.bcg4 = plot(app.UIAxes6,app.Data.time(app.Features.RBCG_A.J(plotBeat,2)),app.Features.RBCG_A.J(plotBeat,1),'ob');
            app.FtHandle.bcg5 = plot(app.UIAxes6,app.Data.time(app.Features.RBCG_A.I(plotBeat,2)),app.Features.RBCG_A.I(plotBeat,1),'or');
            app.FtHandle.bcg6 = plot(app.UIAxes6,app.Data.time(app.Features.RBCG_A.K(plotBeat,2)),app.Features.RBCG_A.K(plotBeat,1),'og');
            app.FtHandle.bcg15 = plot(app.UIAxes6,app.Data.time(app.Features.RBCG_A.H(plotBeat,2)),app.Features.RBCG_A.H(plotBeat,1),'oc');
            app.FtHandle.bcg16 = plot(app.UIAxes6,app.Data.time(app.Features.RBCG_A.L(plotBeat,2)),app.Features.RBCG_A.L(plotBeat,1),'om');
            end
            % BCG_D
            if or(flag,and(~flag,app.BCGDButton.Value))
            [app.Features.BCG_D,plotBeat] = findFeatures(app.Features.BCG_D,app.Data.BCGIntKeep,'BCG',app.Data.param.BCG_D,app.Data.PeakExc,app.Data.fs,1,length(app.Data.PeakExc));
            hold(app.UIAxes7,'on')
            if isfield(app.FtHandle,'bcg7')
                    delete(app.FtHandle.bcg7)
                    delete(app.FtHandle.bcg8)
                    delete(app.FtHandle.bcg9)
                    delete(app.FtHandle.bcg17)
                    delete(app.FtHandle.bcg18)
            end
            app.FtHandle.bcg7 = plot(app.UIAxes7,app.Data.time(app.Features.BCG_D.J(plotBeat,2)),app.Features.BCG_D.J(plotBeat,1),'ob');
            app.FtHandle.bcg8 = plot(app.UIAxes7,app.Data.time(app.Features.BCG_D.I(plotBeat,2)),app.Features.BCG_D.I(plotBeat,1),'or');
            app.FtHandle.bcg9 = plot(app.UIAxes7,app.Data.time(app.Features.BCG_D.K(plotBeat,2)),app.Features.BCG_D.K(plotBeat,1),'og');
            app.FtHandle.bcg17 = plot(app.UIAxes7,app.Data.time(app.Features.BCG_D.H(plotBeat,2)),app.Features.BCG_D.H(plotBeat,1),'oc');
            app.FtHandle.bcg18 = plot(app.UIAxes7,app.Data.time(app.Features.BCG_D.L(plotBeat,2)),app.Features.BCG_D.L(plotBeat,1),'om');
            end
            % Rotated BCG_D
            if or(flag,and(~flag,app.RBCGDButton.Value))
            [app.Features.RBCG_D,plotBeat] = findFeatures(app.Features.RBCG_D,app.Data.RotBCGIntKeep(:,1),'BCG',app.Data.param.RBCG_D,app.Data.PeakExc,app.Data.fs,1,length(app.Data.PeakExc));
            hold(app.UIAxes8,'on')
            if isfield(app.FtHandle,'bcg10')
                    delete(app.FtHandle.bcg10)
                    delete(app.FtHandle.bcg11)
                    delete(app.FtHandle.bcg12)
                    delete(app.FtHandle.bcg19)
                    delete(app.FtHandle.bcg20)
            end
            app.FtHandle.bcg10 = plot(app.UIAxes8,app.Data.time(app.Features.RBCG_D.J(plotBeat,2)),app.Features.RBCG_D.J(plotBeat,1),'ob');
            app.FtHandle.bcg11 = plot(app.UIAxes8,app.Data.time(app.Features.RBCG_D.I(plotBeat,2)),app.Features.RBCG_D.I(plotBeat,1),'or');
            app.FtHandle.bcg12 = plot(app.UIAxes8,app.Data.time(app.Features.RBCG_D.K(plotBeat,2)),app.Features.RBCG_D.K(plotBeat,1),'og');
            app.FtHandle.bcg19 = plot(app.UIAxes8,app.Data.time(app.Features.RBCG_D.H(plotBeat,2)),app.Features.RBCG_D.H(plotBeat,1),'oc');
            app.FtHandle.bcg20 = plot(app.UIAxes8,app.Data.time(app.Features.RBCG_D.L(plotBeat,2)),app.Features.RBCG_D.L(plotBeat,1),'om');
            end
            app.FeatButtFlag = 1;
            app.Features.plotBeat = plotBeat;
        end

        % Value changed function: SPEndIntvEditField
        function SPEndIntvEditFieldValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BP1.SPEnd = app.SPEndIntvEditField.Value/100*fs;
            FindCurrWinFeat(app,'BP1')
            DrawUpdated(app,'BP1')
        end

        % Value changed function: SPMinHgtEditField
        function SPMinHgtEditFieldValueChanged(app, event)
            app.Data.param.BP1.SPMinPeakHeight = app.SPMinHgtEditField.Value;
            FindCurrWinFeat(app,'BP1')
            DrawUpdated(app,'BP1')
        end

        % Value changed function: DPMaxHgtEditField
        function DPMaxHgtEditFieldValueChanged(app, event)
            app.Data.param.BP1.DPMinPeakHeight = -app.DPMaxHgtEditField.Value;
            FindCurrWinFeat(app,'BP1')
            DrawUpdated(app,'BP1')
        end

        % Value changed function: SPEndIntvEditField_2
        function SPEndIntvEditField_2ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BP2.SPEnd = app.SPEndIntvEditField_2.Value/100*fs;
            FindCurrWinFeat(app,'BP2')
            DrawUpdated(app,'BP2')
        end

        % Value changed function: SPMinHgtEditField_2
        function SPMinHgtEditField_2ValueChanged(app, event)
            app.Data.param.BP2.SPMinPeakHeight = app.SPMinHgtEditField_2.Value;
            FindCurrWinFeat(app,'BP2')
            DrawUpdated(app,'BP2')
        end

        % Value changed function: DPMaxHgtEditField_2
        function DPMaxHgtEditField_2ValueChanged(app, event)
            app.Data.param.BP2.DPMinPeakHeight = -app.DPMaxHgtEditField_2.Value;
            FindCurrWinFeat(app,'BP2')
            DrawUpdated(app,'BP2')
        end

        % Value changed function: IMinPeakHgtEditField
        function IMinPeakHgtEditFieldValueChanged(app, event)
            app.Data.param.BCG_A.IMinPeakHeight = app.IMinPeakHgtEditField.Value;
            FindCurrWinFeat(app,'BCGA')
            DrawUpdated(app,'BCGA')
        end

        % Value changed function: JMinPeakHgtEditField
        function JMinPeakHgtEditFieldValueChanged(app, event)
            app.Data.param.BCG_A.JMinPeakHeight = app.JMinPeakHgtEditField.Value;
            FindCurrWinFeat(app,'BCGA')
            DrawUpdated(app,'BCGA')
        end

        % Value changed function: KMinPeakHgtEditField
        function KMinPeakHgtEditFieldValueChanged(app, event)
            app.Data.param.BCG_A.KMinPeakHeight = app.KMinPeakHgtEditField.Value;
            FindCurrWinFeat(app,'BCGA')
            DrawUpdated(app,'BCGA')
        end

        % Value changed function: BtJEndEditField
        function BtJEndEditFieldValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BCG_A.JEnd = app.BtJEndEditField.Value/100*fs;
            FindCurrWinFeat(app,'BCGA')
            DrawUpdated(app,'BCGA')
        end

        % Value changed function: BtKEndEditField
        function BtKEndEditFieldValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BCG_A.KEnd = app.BtKEndEditField.Value/100*fs;
            FindCurrWinFeat(app,'BCGA')
            DrawUpdated(app,'BCGA')
        end

        % Value changed function: IMinPeakHgtEditField_2
        function IMinPeakHgtEditField_2ValueChanged(app, event)
            app.Data.param.RBCG_A.IMinPeakHeight = app.IMinPeakHgtEditField_2.Value;
            FindCurrWinFeat(app,'RBCGA')
            DrawUpdated(app,'RBCGA')
        end

        % Value changed function: JMinPeakHgtEditField_2
        function JMinPeakHgtEditField_2ValueChanged(app, event)
            app.Data.param.RBCG_A.JMinPeakHeight = app.JMinPeakHgtEditField_2.Value;
            FindCurrWinFeat(app,'RBCGA')
            DrawUpdated(app,'RBCGA')
        end

        % Value changed function: KMinPeakHgtEditField_2
        function KMinPeakHgtEditField_2ValueChanged(app, event)
            app.Data.param.RBCG_A.KMinPeakHeight = app.KMinPeakHgtEditField_2.Value;
            FindCurrWinFeat(app,'RBCGA')
            DrawUpdated(app,'RBCGA')
        end

        % Value changed function: BtJEndEditField_2
        function BtJEndEditField_2ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.RBCG_A.JEnd = app.BtJEndEditField_2.Value/100*fs;
            FindCurrWinFeat(app,'RBCGA')
            DrawUpdated(app,'RBCGA')
        end

        % Value changed function: BtKEndEditField_2
        function BtKEndEditField_2ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.RBCG_A.KEnd = app.BtKEndEditField_2.Value/100*fs;
            FindCurrWinFeat(app,'RBCGA')
            DrawUpdated(app,'RBCGA')
        end

        % Value changed function: IMinPeakHgtEditField_3
        function IMinPeakHgtEditField_3ValueChanged(app, event)
            app.Data.param.BCG_D.IMinPeakHeight = app.IMinPeakHgtEditField_3.Value;
            FindCurrWinFeat(app,'BCGD')
            DrawUpdated(app,'BCGD')
        end

        % Value changed function: JMinPeakHgtEditField_3
        function JMinPeakHgtEditField_3ValueChanged(app, event)
            app.Data.param.BCG_D.JMinPeakHeight = app.JMinPeakHgtEditField_3.Value;
            FindCurrWinFeat(app,'BCGD')
            DrawUpdated(app,'BCGD')
        end

        % Value changed function: KMinPeakHgtEditField_3
        function KMinPeakHgtEditField_3ValueChanged(app, event)
            app.Data.param.BCG_D.KMinPeakHeight = app.KMinPeakHgtEditField_3.Value;
            FindCurrWinFeat(app,'BCGD')
            DrawUpdated(app,'BCGD')
        end

        % Value changed function: BtJEndEditField_3
        function BtJEndEditField_3ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BCG_D.JEnd = app.BtJEndEditField_3.Value/100*fs;
            FindCurrWinFeat(app,'BCGD')
            DrawUpdated(app,'BCGD')
        end

        % Value changed function: BtKEndEditField_3
        function BtKEndEditField_3ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BCG_D.KEnd = app.BtKEndEditField_3.Value/100*fs;
            FindCurrWinFeat(app,'BCGD')
            DrawUpdated(app,'BCGD')
        end

        % Value changed function: IMinPeakHgtEditField_4
        function IMinPeakHgtEditField_4ValueChanged(app, event)
            app.Data.param.RBCG_D.IMinPeakHeight = app.IMinPeakHgtEditField_4.Value;
            FindCurrWinFeat(app,'RBCGD')
            DrawUpdated(app,'RBCGD')
        end

        % Value changed function: JMinPeakHgtEditField_4
        function JMinPeakHgtEditField_4ValueChanged(app, event)
            app.Data.param.RBCG_D.JMinPeakHeight = app.JMinPeakHgtEditField_4.Value;
            FindCurrWinFeat(app,'RBCGD')
            DrawUpdated(app,'RBCGD')
        end

        % Value changed function: KMinPeakHgtEditField_4
        function KMinPeakHgtEditField_4ValueChanged(app, event)
            app.Data.param.RBCG_D.KMinPeakHeight = app.KMinPeakHgtEditField_4.Value;
            FindCurrWinFeat(app,'RBCGD')
            DrawUpdated(app,'RBCGD')
        end

        % Value changed function: BtJEndEditField_4
        function BtJEndEditField_4ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.RBCG_D.JEnd = app.BtJEndEditField_4.Value/100*fs;
            FindCurrWinFeat(app,'RBCGD')
            DrawUpdated(app,'RBCGD')
        end

        % Value changed function: BtKEndEditField_4
        function BtKEndEditField_4ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.RBCG_D.KEnd = app.BtKEndEditField_4.Value/100*fs;
            FindCurrWinFeat(app,'RBCGD')
            DrawUpdated(app,'RBCGD')
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            if app.BP1Button.Value == 1
                [app.Features.BP1,app.Features.plotBeat] = RemoveFeatures(app.Features.BP1,'BP1',app.Data.PeakExc,app.CurrWinBeat(1),app.CurrWinBeat(end));
                DrawUpdated(app,'BP1')
            elseif app.BP2Button.Value == 1
                [app.Features.BP2,app.Features.plotBeat] = RemoveFeatures(app.Features.BP2,'BP2',app.Data.PeakExc,app.CurrWinBeat(1),app.CurrWinBeat(end));
                DrawUpdated(app,'BP2')
            elseif app.BCGAButton.Value == 1
                [app.Features.BCG_A,app.Features.plotBeat] = RemoveFeatures(app.Features.BCG_A,'BCGA',app.Data.PeakExc,app.CurrWinBeat(1),app.CurrWinBeat(end));
                DrawUpdated(app,'BCGA')
            elseif app.RBCGAButton.Value == 1
                [app.Features.RBCG_A,app.Features.plotBeat] = RemoveFeatures(app.Features.RBCG_A,'RBCGA',app.Data.PeakExc,app.CurrWinBeat(1),app.CurrWinBeat(end));
                DrawUpdated(app,'RBCGA')
            elseif app.BCGDButton.Value == 1
                [app.Features.BCG_D,app.Features.plotBeat] = RemoveFeatures(app.Features.BCG_D,'BCGD',app.Data.PeakExc,app.CurrWinBeat(1),app.CurrWinBeat(end));
                DrawUpdated(app,'BCGD')
            elseif app.RBCGDButton.Value == 1
                [app.Features.RBCG_D,app.Features.plotBeat] = RemoveFeatures(app.Features.RBCG_D,'RBCGD',app.Data.PeakExc,app.CurrWinBeat(1),app.CurrWinBeat(end));
                DrawUpdated(app,'RBCGD')
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.Features.Event = app.Data.Event;
            RepresentativeButtonPushed(app)
            app.Features.BeatNo = FindBeatNo(app.Data.BeatNo, app.Data.PeakSam, app.Features.plotBeat); % # beats with features included
            features = app.Features;
            save([app.DirectoryPath,'\Feature_',app.Data.Event],'features')
%             save(['D:\Project-AHA\Data For GUI\Feature_',app.Data.Event],'features')
            fprintf('Intervention Features saved!')
        end

        % Button pushed function: MovAveButton
        function MovAveButtonPushed(app, event)
            if isempty(app.WinNo)
                app.WinNo = 10;
            end
            [app.Features.MovAv,~,app.Features.MinMaxLoc] = MovAve(app.Features,app.WinNo,'max','BP1');
            DrawMovAv(app)
        end

        % Value changed function: MovWindowSpinner
        function MovWindowSpinnerValueChanged(app, event)
            app.WinNo = app.MovWindowSpinner.Value;
            
        end

        % Button pushed function: MinMaxWinButton_2
        function MinMaxWinButton_2Pushed(app, event)
            
        end

        % Value changed function: JStartEditField_4
        function JStartEditField_4ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.RBCG_D.JSt = app.JStartEditField_4.Value/100*fs;
            FindCurrWinFeat(app,'RBCGD')
            DrawUpdated(app,'RBCGD')
        end

        % Value changed function: JStartEditField_3
        function JStartEditField_3ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BCG_D.JSt = app.JStartEditField_3.Value/100*fs;
            FindCurrWinFeat(app,'BCGD')
            DrawUpdated(app,'BCGD')
        end

        % Value changed function: JStartEditField_2
        function JStartEditField_2ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.RBCG_A.JSt = app.JStartEditField_2.Value/100*fs;
            FindCurrWinFeat(app,'RBCGA')
            DrawUpdated(app,'RBCGA')
        end

        % Value changed function: JStartEditField
        function JStartEditFieldValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BCG_A.JSt = app.JStartEditField.Value/100*fs;
            FindCurrWinFeat(app,'BCGA')
            DrawUpdated(app,'BCGA')
        end

        % Value changed function: SPStIntvEditField
        function SPStIntvEditFieldValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BP1.SPSt = app.SPStIntvEditField.Value/100*fs;
            FindCurrWinFeat(app,'BP1')
            DrawUpdated(app,'BP1')
        end

        % Value changed function: SPStIntvEditField_2
        function SPStIntvEditField_2ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BP2.SPSt = app.SPStIntvEditField_2.Value/100*fs;
            FindCurrWinFeat(app,'BP2')
            DrawUpdated(app,'BP2')
        end

        % Button pushed function: PlotButton_2
        function RepresentativeButtonPushed(app, event)
            [app.Features.BP1Rep, app.Features.BP1Std] = Representative(app.Data.BP1,'BP',app.Data.PeakExc,1,length(app.Data.PeakExc));
            [app.Features.BP2Rep, app.Features.BP2Std] = Representative(app.Data.BP2,'BP',app.Data.PeakExc,1,length(app.Data.PeakExc));
            [app.Features.BCGRep, app.Features.BCGStd] = Representative(app.Data.BCGKeep,'BCG',app.Data.PeakExc,1,length(app.Data.PeakExc));
            [app.Features.BCGIntRep, app.Features.BCGIntStd] = Representative(app.Data.BCGIntKeep,'BCG',app.Data.PeakExc,1,length(app.Data.PeakExc));
            [app.Features.rotBCGRep, app.Features.rotBCGStd] = Representative(app.Data.RotBCGKeep,'BCG',app.Data.PeakExc,1,length(app.Data.PeakExc));
            [app.Features.rotBCGIntRep, app.Features.rotBCGIntStd] = Representative(app.Data.RotBCGIntKeep,'BCG',app.Data.PeakExc,1,length(app.Data.PeakExc));
            DrawTabRep(app)
        end

        % Value changed function: LimMotionEditField
        function LimMotionEditFieldValueChanged(app, event)
            value = app.LimMotionEditField.Value;
            Preprocess(app,1)
            ClearFig(app)
            Draw(app,0)
        end

        % Value changed function: MACheckBox
        function MACheckBoxValueChanged(app, event)
            value = app.MACheckBox.Value;
            Preprocess(app,0)
            ClearFig(app)
            Draw(app,0)
        end

        % Value changed function: FlipCheckBox
        function FlipCheckBoxValueChanged(app, event)
            value = app.FlipCheckBox.Value;
            Flip(app)
            Preprocess(app,1)
            ClearFig(app)
            Draw(app,0)
            fprintf('BCG waves were flipped!')
        end

        % Value changed function: HPfrEditField
        function HPfrEditFieldValueChanged(app, event)
            app.Data.param.CutOff = app.HPfrEditField.Value;
            Preprocess(app,1)
            ClearFig(app)
            Draw(app,0)
            fprintf('BCG was updated!')
        end

        % Value changed function: HPfrIntEditField
        function HPfrIntEditFieldValueChanged(app, event)
            app.Data.param.CutOffInt = app.HPfrIntEditField.Value;
            Preprocess(app,1)
            ClearFig(app)
            Draw(app,0)
            fprintf('BCG was updated!')
        end

        % Button pushed function: WinFeatureButton
        function WinFeatureButtonPushed(app, event)
            if app.BP1Button.Value == 1
                FindCurrWinFeat(app,'BP1')
                DrawUpdated(app,'BP1')
            elseif app.BP2Button.Value == 1
                FindCurrWinFeat(app,'BP2')
                DrawUpdated(app,'BP2')
            elseif app.BCGAButton.Value == 1
                FindCurrWinFeat(app,'BCGA')
                DrawUpdated(app,'BCGA')
            elseif app.RBCGAButton.Value == 1
                FindCurrWinFeat(app,'RBCGA')
                DrawUpdated(app,'RBCGA')
            elseif app.BCGDButton.Value == 1
                FindCurrWinFeat(app,'BCGD')
                DrawUpdated(app,'BCGD')
            elseif app.RBCGDButton.Value == 1
                FindCurrWinFeat(app,'RBCGD')
                DrawUpdated(app,'RBCGD')
            end
        end

        % Value changed function: Slider1_2
        function Slider1_2ValueChanged(app, event)
            value = app.Slider1_2.Value;
            axCurr(1) = app.axKeepFeat(1);
            axCurr(2) = app.axKeepFeat(1)+app.Data.time(end)/10;
            app.slider1_2Val = value;
            axLim = [axCurr(1) axCurr(1)+(100-value)/100*(axCurr(2)-axCurr(1))];
            axLimMin = [axCurr(1) axCurr(1)+0.05*(axCurr(2)-axCurr(1))];
            if value<99
            app.UIAxes1_2.XLim = axLim;
            app.UIAxes2_2.XLim = axLim;
            app.UIAxes3_2.XLim = axLim;
            app.UIAxes4_2.XLim = axLim;
            app.UIAxes5_2.XLim = axLim;
            app.UIAxes6_2.XLim = axLim;
            app.UIAxes7_2.XLim = axLim;
            app.UIAxes8_2.XLim = axLim;
            else
                app.UIAxes1_2.XLim = axLimMin;
                app.UIAxes2_2.XLim = axLimMin;
                app.UIAxes3_2.XLim = axLimMin;
                app.UIAxes4_2.XLim = axLimMin;
                app.UIAxes5_2.XLim = axLimMin;
                app.UIAxes6_2.XLim = axLimMin;
                app.UIAxes7_2.XLim = axLimMin;
                app.UIAxes8_2.XLim = axLimMin;
            end
            CurrWindowFeat(app)
        end

        % Value changed function: Slider2_2
        function Slider2_2ValueChanged(app, event)
            value = app.Slider2_2.Value;
            axLim = 1.1*app.Data.time(end)*[value-10 value]/100;
            if ~isempty(app.slider1_2Val)
                axLim = [axLim(1) axLim(1)+(100-app.slider1_2Val)/100*(axLim(2)-axLim(1))];            
            end
            if value>=0
            app.UIAxes1_2.XLim = axLim;
            app.UIAxes2_2.XLim = axLim;
            app.UIAxes3_2.XLim = axLim;
            app.UIAxes4_2.XLim = axLim;
            app.UIAxes5_2.XLim = axLim;
            app.UIAxes6_2.XLim = axLim;
            app.UIAxes7_2.XLim = axLim;
            app.UIAxes8_2.XLim = axLim;
            app.axKeepFeat = app.UIAxes1_2.XLim;
            end         
            CurrWindowFeat(app)
        end

        % Value changed function: IStartEditField
        function IStartEditFieldValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BCG_A.ISt = app.IStartEditField.Value/100*fs;
            FindCurrWinFeat(app,'BCGA')
            DrawUpdated(app,'BCGA')
        end

        % Value changed function: JMinPromEditField
        function JMinPromEditFieldValueChanged(app, event)
            app.Data.param.BCG_A.JProm = app.JMinPromEditField.Value;
            FindCurrWinFeat(app,'BCGA')
            DrawUpdated(app,'BCGA')
        end

        % Value changed function: HStartEditField
        function HStartEditFieldValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BCG_A.HSt = app.HStartEditField.Value/100*fs;
            FindCurrWinFeat(app,'BCGA')
            DrawUpdated(app,'BCGA')
        end

        % Value changed function: HMinPeakHgtEditField
        function HMinPeakHgtEditFieldValueChanged(app, event)
            app.Data.param.BCG_A.HMinPeakHeight = app.HMinPeakHgtEditField.Value;
            FindCurrWinFeat(app,'BCGA')
            DrawUpdated(app,'BCGA')
        end

        % Value changed function: LMinPeakHgtEditField
        function LMinPeakHgtEditFieldValueChanged(app, event)
            app.Data.param.BCG_A.LMinPeakHeight = app.LMinPeakHgtEditField.Value;
            FindCurrWinFeat(app,'BCGA')
            DrawUpdated(app,'BCGA')
        end

        % Value changed function: LEndEditField
        function LEndEditFieldValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BCG_A.LEnd = app.LEndEditField.Value/100*fs;
            FindCurrWinFeat(app,'BCGA')
            DrawUpdated(app,'BCGA')
        end

        % Value changed function: JMinPromEditField_2
        function JMinPromEditField_2ValueChanged(app, event)
            app.Data.param.RBCG_A.JProm = app.JMinPromEditField_2.Value;
            FindCurrWinFeat(app,'RBCGA')
            DrawUpdated(app,'RBCGA')
        end

        % Value changed function: IStartEditField_2
        function IStartEditField_2ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.RBCG_A.ISt = app.IStartEditField_2.Value/100*fs;
            FindCurrWinFeat(app,'RBCGA')
            DrawUpdated(app,'RBCGA')
        end

        % Value changed function: HStartEditField_2
        function HStartEditField_2ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.RBCG_A.HSt = app.HStartEditField_2.Value/100*fs;
            FindCurrWinFeat(app,'RBCGA')
            DrawUpdated(app,'RBCGA')
        end

        % Value changed function: HMinPeakHgtEditField_2
        function HMinPeakHgtEditField_2ValueChanged(app, event)
            app.Data.param.RBCG_A.HMinPeakHeight = app.HMinPeakHgtEditField_2.Value;
            FindCurrWinFeat(app,'RBCGA')
            DrawUpdated(app,'RBCGA')
        end

        % Value changed function: LMinPeakHgtEditField_2
        function LMinPeakHgtEditField_2ValueChanged(app, event)
            app.Data.param.RBCG_A.LMinPeakHeight = app.LMinPeakHgtEditField_2.Value;
            FindCurrWinFeat(app,'RBCGA')
            DrawUpdated(app,'RBCGA')
        end

        % Value changed function: LEndEditField_2
        function LEndEditField_2ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.RBCG_A.LEnd = app.LEndEditField_2.Value/100*fs;
            FindCurrWinFeat(app,'RBCGA')
            DrawUpdated(app,'RBCGA')
        end

        % Value changed function: JMinPromEditField_3
        function JMinPromEditField_3ValueChanged(app, event)
            app.Data.param.BCG_D.JProm = app.JMinPromEditField_3.Value;
            FindCurrWinFeat(app,'BCGD')
            DrawUpdated(app,'BCGD')
        end

        % Value changed function: IStartEditField_3
        function IStartEditField_3ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BCG_D.ISt = app.IStartEditField_3.Value/100*fs;
            FindCurrWinFeat(app,'BCGD')
            DrawUpdated(app,'BCGD')
        end

        % Value changed function: HStartEditField_3
        function HStartEditField_3ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BCG_D.HSt = app.HStartEditField_3.Value/100*fs;
            FindCurrWinFeat(app,'BCGD')
            DrawUpdated(app,'BCGD')
        end

        % Value changed function: HMinPeakHgtEditField_3
        function HMinPeakHgtEditField_3ValueChanged(app, event)
            app.Data.param.BCG_D.HMinPeakHeight = app.HMinPeakHgtEditField_3.Value;
            FindCurrWinFeat(app,'BCGD')
            DrawUpdated(app,'BCGD')
        end

        % Value changed function: LMinPeakHgtEditField_3
        function LMinPeakHgtEditField_3ValueChanged(app, event)
            app.Data.param.BCG_D.LMinPeakHeight = app.LMinPeakHgtEditField_3.Value;
            FindCurrWinFeat(app,'BCGD')
            DrawUpdated(app,'BCGD')
        end

        % Value changed function: LEndEditField_3
        function LEndEditField_3ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.BCG_D.LEnd = app.LEndEditField_3.Value/100*fs;
            FindCurrWinFeat(app,'BCGD')
            DrawUpdated(app,'BCGD')
        end

        % Value changed function: JMinPromEditField_4
        function JMinPromEditField_4ValueChanged(app, event)
            app.Data.param.RBCG_D.JProm = app.JMinPromEditField_4.Value;
            FindCurrWinFeat(app,'RBCGD')
            DrawUpdated(app,'RBCGD')
        end

        % Value changed function: IStartEditField_4
        function IStartEditField_4ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.RBCG_D.ISt = app.IStartEditField_4.Value/100*fs;
            FindCurrWinFeat(app,'RBCGD')
            DrawUpdated(app,'RBCGD')
        end

        % Value changed function: HStartEditField_4
        function HStartEditField_4ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.RBCG_D.HSt = app.HStartEditField_4.Value/100*fs;
            FindCurrWinFeat(app,'RBCGD')
            DrawUpdated(app,'RBCGD')
        end

        % Value changed function: HMinPeakHgtEditField_4
        function HMinPeakHgtEditField_4ValueChanged(app, event)
            app.Data.param.RBCG_D.HMinPeakHeight = app.HMinPeakHgtEditField_4.Value;
            FindCurrWinFeat(app,'RCGD')
            DrawUpdated(app,'RBCGD')
        end

        % Value changed function: LMinPeakHgtEditField_4
        function LMinPeakHgtEditField_4ValueChanged(app, event)
            app.Data.param.RBCG_D.LMinPeakHeight = app.LMinPeakHgtEditField_4.Value;
            FindCurrWinFeat(app,'RCGD')
            DrawUpdated(app,'RBCGD')
        end

        % Value changed function: LEndEditField_4
        function LEndEditField_4ValueChanged(app, event)
            fs = app.Data.fs;
            app.Data.param.RBCG_D.LEnd = app.LEndEditField_4.Value/100*fs;
            FindCurrWinFeat(app,'RBCGD')
            DrawUpdated(app,'RBCGD')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.GridLayout = uigridlayout(app.Tab);
            app.UIAxes1 = uiaxes(app.GridLayout);
            title(app.UIAxes1, 'Intervention')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, 'ECG_t')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, 'ECG_b')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, 'BP_1')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, 'BP_2')
            app.UIAxes5 = uiaxes(app.GridLayout);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, 'BCG Acc')
            app.UIAxes6 = uiaxes(app.GridLayout);
            title(app.UIAxes6, '')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, 'RotBCG Acc')
            app.UIAxes7 = uiaxes(app.GridLayout);
            title(app.UIAxes7, '')
            xlabel(app.UIAxes7, '')
            ylabel(app.UIAxes7, 'BCG Disp')
            app.UIAxes8 = uiaxes(app.GridLayout);
            title(app.UIAxes8, '')
            xlabel(app.UIAxes8, '')
            ylabel(app.UIAxes8, 'RotBCG Disp')
            app.UIAxes8.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Slider1 = uislider(app.GridLayout2);
            app.Slider1.MajorTickLabels = {''};
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged, true);
            app.Slider2 = uislider(app.GridLayout2);
            app.Slider2.MajorTickLabels = {''};
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout13 = uigridlayout(app.GridLayout3);
            app.LimMotionEditFieldLabel = uilabel(app.GridLayout13);
            app.LimMotionEditFieldLabel.Text = 'Lim Motion';
            app.LimMotionEditField = uieditfield(app.GridLayout13, 'numeric');
            app.LimMotionEditField.ValueChangedFcn = createCallbackFcn(app, @LimMotionEditFieldValueChanged, true);
            app.LimMotionEditField.Value = 6;
            app.GridLayout16 = uigridlayout(app.GridLayout13);
            app.GridLayout17 = uigridlayout(app.GridLayout3);
            app.FlipCheckBox = uicheckbox(app.GridLayout17);
            app.FlipCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipCheckBoxValueChanged, true);
            app.FlipCheckBox.Text = 'Flip';
            app.FlipCheckBox.Value = true;
            app.MACheckBox = uicheckbox(app.GridLayout17);
            app.MACheckBox.ValueChangedFcn = createCallbackFcn(app, @MACheckBoxValueChanged, true);
            app.MACheckBox.Text = 'MA';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.BP1Button = uiradiobutton(app.ButtonGroup);
            app.BP1Button.Text = 'BP1';
            app.BP1Button.Value = true;
            app.BP2Button = uiradiobutton(app.ButtonGroup);
            app.BP2Button.Text = 'BP2';
            app.RBCGAButton = uiradiobutton(app.ButtonGroup);
            app.RBCGAButton.Text = 'RBCG A';
            app.BCGDButton = uiradiobutton(app.ButtonGroup);
            app.BCGDButton.Text = 'BCG D';
            app.BCGAButton = uiradiobutton(app.ButtonGroup);
            app.BCGAButton.Text = 'BCG A';
            app.RBCGDButton = uiradiobutton(app.ButtonGroup);
            app.RBCGDButton.Text = 'RBCG D';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.BP1Tab = uitab(app.TabGroup);
            app.BP1Tab.Title = 'BP1';
            app.GridLayout4 = uigridlayout(app.BP1Tab);
            app.Label = uilabel(app.GridLayout4);
            app.SPEndIntvEditFieldLabel = uilabel(app.GridLayout4);
            app.SPEndIntvEditFieldLabel.Text = '% SP End Intv';
            app.SPEndIntvEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SPEndIntvEditField.ValueChangedFcn = createCallbackFcn(app, @SPEndIntvEditFieldValueChanged, true);
            app.SPMinHgtEditFieldLabel = uilabel(app.GridLayout4);
            app.SPMinHgtEditFieldLabel.Text = 'SP MinHgt';
            app.SPMinHgtEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SPMinHgtEditField.ValueChangedFcn = createCallbackFcn(app, @SPMinHgtEditFieldValueChanged, true);
            app.DPMaxHgtEditFieldLabel = uilabel(app.GridLayout4);
            app.DPMaxHgtEditFieldLabel.Text = 'DP MaxHgt';
            app.DPMaxHgtEditField = uieditfield(app.GridLayout4, 'numeric');
            app.DPMaxHgtEditField.ValueChangedFcn = createCallbackFcn(app, @DPMaxHgtEditFieldValueChanged, true);
            app.Label2 = uilabel(app.GridLayout4);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.GridLayout4);
            app.Label3.Text = 'Label3';
            app.SPStIntvEditField_2Label = uilabel(app.GridLayout4);
            app.SPStIntvEditField_2Label.Text = 'SP St Intv';
            app.SPStIntvEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SPStIntvEditField.ValueChangedFcn = createCallbackFcn(app, @SPStIntvEditFieldValueChanged, true);
            app.Label4 = uilabel(app.GridLayout4);
            app.Label4.Text = 'Label4';
            app.BP2Tab = uitab(app.TabGroup);
            app.BP2Tab.Title = 'BP2';
            app.GridLayout4_2 = uigridlayout(app.BP2Tab);
            app.Label_2 = uilabel(app.GridLayout4_2);
            app.SPEndIntvEditField_2Label = uilabel(app.GridLayout4_2);
            app.SPEndIntvEditField_2Label.Text = '% SP End Intv';
            app.SPEndIntvEditField_2 = uieditfield(app.GridLayout4_2, 'numeric');
            app.SPEndIntvEditField_2.ValueChangedFcn = createCallbackFcn(app, @SPEndIntvEditField_2ValueChanged, true);
            app.SPMinHgtEditField_2Label = uilabel(app.GridLayout4_2);
            app.SPMinHgtEditField_2Label.Text = 'SP MinHgt';
            app.SPMinHgtEditField_2 = uieditfield(app.GridLayout4_2, 'numeric');
            app.SPMinHgtEditField_2.ValueChangedFcn = createCallbackFcn(app, @SPMinHgtEditField_2ValueChanged, true);
            app.DPMaxHgtEditField_2Label = uilabel(app.GridLayout4_2);
            app.DPMaxHgtEditField_2Label.Text = 'DP MaxHgt';
            app.DPMaxHgtEditField_2 = uieditfield(app.GridLayout4_2, 'numeric');
            app.DPMaxHgtEditField_2.ValueChangedFcn = createCallbackFcn(app, @DPMaxHgtEditField_2ValueChanged, true);
            app.Label2_2 = uilabel(app.GridLayout4_2);
            app.Label2_2.Text = 'Label2';
            app.Label3_2 = uilabel(app.GridLayout4_2);
            app.Label3_2.Text = 'Label3';
            app.SPStIntvEditFieldLabel = uilabel(app.GridLayout4_2);
            app.SPStIntvEditFieldLabel.Text = 'SP St Intv';
            app.SPStIntvEditField_2 = uieditfield(app.GridLayout4_2, 'numeric');
            app.SPStIntvEditField_2.ValueChangedFcn = createCallbackFcn(app, @SPStIntvEditField_2ValueChanged, true);
            app.Label4_2 = uilabel(app.GridLayout4_2);
            app.Label4_2.Text = 'Label4';
            app.BCG_ATab = uitab(app.TabGroup);
            app.BCG_ATab.Title = 'BCG_A';
            app.GridLayout5 = uigridlayout(app.BCG_ATab);
            app.IMinPeakHgtEditFieldLabel = uilabel(app.GridLayout5);
            app.IMinPeakHgtEditFieldLabel.Text = 'IMinPeakHgt';
            app.IMinPeakHgtEditField = uieditfield(app.GridLayout5, 'numeric');
            app.IMinPeakHgtEditField.ValueChangedFcn = createCallbackFcn(app, @IMinPeakHgtEditFieldValueChanged, true);
            app.Label31 = uilabel(app.GridLayout5);
            app.Label31.Text = 'Label31';
            app.JMinPeakHgtEditFieldLabel = uilabel(app.GridLayout5);
            app.JMinPeakHgtEditFieldLabel.Text = 'JMinPeakHgt';
            app.JMinPeakHgtEditField = uieditfield(app.GridLayout5, 'numeric');
            app.JMinPeakHgtEditField.ValueChangedFcn = createCallbackFcn(app, @JMinPeakHgtEditFieldValueChanged, true);
            app.BtJEndEditFieldLabel = uilabel(app.GridLayout5);
            app.BtJEndEditFieldLabel.Text = '% Bt JEnd';
            app.BtJEndEditField = uieditfield(app.GridLayout5, 'numeric');
            app.BtJEndEditField.ValueChangedFcn = createCallbackFcn(app, @BtJEndEditFieldValueChanged, true);
            app.KMinPeakHgtEditFieldLabel = uilabel(app.GridLayout5);
            app.KMinPeakHgtEditFieldLabel.Text = 'KMinPeakHgt';
            app.KMinPeakHgtEditField = uieditfield(app.GridLayout5, 'numeric');
            app.KMinPeakHgtEditField.ValueChangedFcn = createCallbackFcn(app, @KMinPeakHgtEditFieldValueChanged, true);
            app.BtKEndEditFieldLabel = uilabel(app.GridLayout5);
            app.BtKEndEditFieldLabel.Text = '% Bt KEnd';
            app.BtKEndEditField = uieditfield(app.GridLayout5, 'numeric');
            app.BtKEndEditField.ValueChangedFcn = createCallbackFcn(app, @BtKEndEditFieldValueChanged, true);
            app.JStartEditFieldLabel = uilabel(app.GridLayout5);
            app.JStartEditFieldLabel.Text = 'JStart';
            app.JStartEditField = uieditfield(app.GridLayout5, 'numeric');
            app.JStartEditField.ValueChangedFcn = createCallbackFcn(app, @JStartEditFieldValueChanged, true);
            app.Label32 = uilabel(app.GridLayout5);
            app.Label32.Text = 'Label32';
            app.Label33 = uilabel(app.GridLayout5);
            app.Label33.Text = 'Label33';
            app.Label34 = uilabel(app.GridLayout5);
            app.Label34.Text = 'Label34';
            app.Label35 = uilabel(app.GridLayout5);
            app.Label35.Text = 'Label35';
            app.Label36 = uilabel(app.GridLayout5);
            app.Label36.Text = 'Label36';
            app.JMinPromEditFieldLabel = uilabel(app.GridLayout5);
            app.JMinPromEditFieldLabel.Text = 'J Min Prom';
            app.JMinPromEditField = uieditfield(app.GridLayout5, 'numeric');
            app.JMinPromEditField.ValueChangedFcn = createCallbackFcn(app, @JMinPromEditFieldValueChanged, true);
            app.IStartLabel = uilabel(app.GridLayout5);
            app.IStartLabel.Text = 'IStart';
            app.IStartEditField = uieditfield(app.GridLayout5, 'numeric');
            app.IStartEditField.ValueChangedFcn = createCallbackFcn(app, @IStartEditFieldValueChanged, true);
            app.HStartEditFieldLabel = uilabel(app.GridLayout5);
            app.HStartEditFieldLabel.Text = 'HStart';
            app.HStartEditField = uieditfield(app.GridLayout5, 'numeric');
            app.HStartEditField.ValueChangedFcn = createCallbackFcn(app, @HStartEditFieldValueChanged, true);
            app.HMinPeakHgtEditFieldLabel = uilabel(app.GridLayout5);
            app.HMinPeakHgtEditFieldLabel.Text = 'HMinPeakHgt';
            app.HMinPeakHgtEditField = uieditfield(app.GridLayout5, 'numeric');
            app.HMinPeakHgtEditField.ValueChangedFcn = createCallbackFcn(app, @HMinPeakHgtEditFieldValueChanged, true);
            app.LMinPeakHgtEditFieldLabel = uilabel(app.GridLayout5);
            app.LMinPeakHgtEditFieldLabel.Text = 'LMinPeakHgt';
            app.LMinPeakHgtEditField = uieditfield(app.GridLayout5, 'numeric');
            app.LMinPeakHgtEditField.ValueChangedFcn = createCallbackFcn(app, @LMinPeakHgtEditFieldValueChanged, true);
            app.LEndEditFieldLabel = uilabel(app.GridLayout5);
            app.LEndEditFieldLabel.Text = 'LEnd';
            app.LEndEditField = uieditfield(app.GridLayout5, 'numeric');
            app.LEndEditField.ValueChangedFcn = createCallbackFcn(app, @LEndEditFieldValueChanged, true);
            app.Label36_3 = uilabel(app.GridLayout5);
            app.Label36_3.Text = 'Label36';
            app.Label36_4 = uilabel(app.GridLayout5);
            app.Label36_4.Text = 'Label36';
            app.Label36_5 = uilabel(app.GridLayout5);
            app.Label36_5.Text = 'Label36';
            app.Label36_6 = uilabel(app.GridLayout5);
            app.Label36_6.Text = 'Label36';
            app.Label36_7 = uilabel(app.GridLayout5);
            app.Label36_7.Text = 'Label36';
            app.Label36_8 = uilabel(app.GridLayout5);
            app.Label36_8.Text = 'Label36';
            app.RBCG_ATab = uitab(app.TabGroup);
            app.RBCG_ATab.Title = 'RBCG_A';
            app.GridLayout5_2 = uigridlayout(app.RBCG_ATab);
            app.IMinPeakHgtEditField_2Label = uilabel(app.GridLayout5_2);
            app.IMinPeakHgtEditField_2Label.Text = 'IMinPeakHgt';
            app.IMinPeakHgtEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.IMinPeakHgtEditField_2.ValueChangedFcn = createCallbackFcn(app, @IMinPeakHgtEditField_2ValueChanged, true);
            app.Label41 = uilabel(app.GridLayout5_2);
            app.Label41.Text = 'Label31';
            app.JMinPeakHgtEditField_2Label = uilabel(app.GridLayout5_2);
            app.JMinPeakHgtEditField_2Label.Text = 'JMinPeakHgt';
            app.JMinPeakHgtEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.JMinPeakHgtEditField_2.ValueChangedFcn = createCallbackFcn(app, @JMinPeakHgtEditField_2ValueChanged, true);
            app.BtJEndEditField_2Label = uilabel(app.GridLayout5_2);
            app.BtJEndEditField_2Label.Text = '% Bt JEnd';
            app.BtJEndEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.BtJEndEditField_2.ValueChangedFcn = createCallbackFcn(app, @BtJEndEditField_2ValueChanged, true);
            app.KMinPeakHgtEditField_2Label = uilabel(app.GridLayout5_2);
            app.KMinPeakHgtEditField_2Label.Text = 'KMinPeakHgt';
            app.KMinPeakHgtEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.KMinPeakHgtEditField_2.ValueChangedFcn = createCallbackFcn(app, @KMinPeakHgtEditField_2ValueChanged, true);
            app.BtKEndEditField_2Label = uilabel(app.GridLayout5_2);
            app.BtKEndEditField_2Label.Text = '% Bt KEnd';
            app.BtKEndEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.BtKEndEditField_2.ValueChangedFcn = createCallbackFcn(app, @BtKEndEditField_2ValueChanged, true);
            app.JStartEditField_2Label = uilabel(app.GridLayout5_2);
            app.JStartEditField_2Label.Text = 'JStart';
            app.JStartEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.JStartEditField_2.ValueChangedFcn = createCallbackFcn(app, @JStartEditField_2ValueChanged, true);
            app.Label42 = uilabel(app.GridLayout5_2);
            app.Label42.Text = 'Label32';
            app.Label43 = uilabel(app.GridLayout5_2);
            app.Label43.Text = 'Label33';
            app.Label44 = uilabel(app.GridLayout5_2);
            app.Label44.Text = 'Label34';
            app.Label45 = uilabel(app.GridLayout5_2);
            app.Label45.Text = 'Label35';
            app.Label46 = uilabel(app.GridLayout5_2);
            app.Label46.Text = 'Label36';
            app.JMinPromEditField_2Label = uilabel(app.GridLayout5_2);
            app.JMinPromEditField_2Label.Text = 'J Min Prom';
            app.JMinPromEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.JMinPromEditField_2.ValueChangedFcn = createCallbackFcn(app, @JMinPromEditField_2ValueChanged, true);
            app.IStartEditField_2Label = uilabel(app.GridLayout5_2);
            app.IStartEditField_2Label.Text = 'IStart';
            app.IStartEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.IStartEditField_2.ValueChangedFcn = createCallbackFcn(app, @IStartEditField_2ValueChanged, true);
            app.HStartEditField_2Label = uilabel(app.GridLayout5_2);
            app.HStartEditField_2Label.Text = 'HStart';
            app.HStartEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.HStartEditField_2.ValueChangedFcn = createCallbackFcn(app, @HStartEditField_2ValueChanged, true);
            app.HMinPeakHgtEditField_2Label = uilabel(app.GridLayout5_2);
            app.HMinPeakHgtEditField_2Label.Text = 'HMinPeakHgt';
            app.HMinPeakHgtEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.HMinPeakHgtEditField_2.ValueChangedFcn = createCallbackFcn(app, @HMinPeakHgtEditField_2ValueChanged, true);
            app.LMinPeakHgtEditField_2Label = uilabel(app.GridLayout5_2);
            app.LMinPeakHgtEditField_2Label.Text = 'LMinPeakHgt';
            app.LMinPeakHgtEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.LMinPeakHgtEditField_2.ValueChangedFcn = createCallbackFcn(app, @LMinPeakHgtEditField_2ValueChanged, true);
            app.LEndEditField_2Label = uilabel(app.GridLayout5_2);
            app.LEndEditField_2Label.Text = 'LEnd';
            app.LEndEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.LEndEditField_2.ValueChangedFcn = createCallbackFcn(app, @LEndEditField_2ValueChanged, true);
            app.Label46_3 = uilabel(app.GridLayout5_2);
            app.Label46_3.Text = 'Label36';
            app.Label46_4 = uilabel(app.GridLayout5_2);
            app.Label46_4.Text = 'Label36';
            app.Label46_5 = uilabel(app.GridLayout5_2);
            app.Label46_5.Text = 'Label36';
            app.Label46_6 = uilabel(app.GridLayout5_2);
            app.Label46_6.Text = 'Label36';
            app.Label46_7 = uilabel(app.GridLayout5_2);
            app.Label46_7.Text = 'Label36';
            app.Label46_8 = uilabel(app.GridLayout5_2);
            app.Label46_8.Text = 'Label36';
            app.BCG_DTab = uitab(app.TabGroup);
            app.BCG_DTab.Title = 'BCG_D';
            app.GridLayout5_3 = uigridlayout(app.BCG_DTab);
            app.IMinPeakHgtEditField_3Label = uilabel(app.GridLayout5_3);
            app.IMinPeakHgtEditField_3Label.Text = 'IMinPeakHgt';
            app.IMinPeakHgtEditField_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.IMinPeakHgtEditField_3.ValueChangedFcn = createCallbackFcn(app, @IMinPeakHgtEditField_3ValueChanged, true);
            app.Label31_2 = uilabel(app.GridLayout5_3);
            app.Label31_2.Text = 'Label31';
            app.JMinPeakHgtEditField_3Label = uilabel(app.GridLayout5_3);
            app.JMinPeakHgtEditField_3Label.Text = 'JMinPeakHgt';
            app.JMinPeakHgtEditField_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.JMinPeakHgtEditField_3.ValueChangedFcn = createCallbackFcn(app, @JMinPeakHgtEditField_3ValueChanged, true);
            app.BtJEndEditField_3Label = uilabel(app.GridLayout5_3);
            app.BtJEndEditField_3Label.Text = '% Bt JEnd';
            app.BtJEndEditField_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.BtJEndEditField_3.ValueChangedFcn = createCallbackFcn(app, @BtJEndEditField_3ValueChanged, true);
            app.KMinPeakHgtEditField_3Label = uilabel(app.GridLayout5_3);
            app.KMinPeakHgtEditField_3Label.Text = 'KMinPeakHgt';
            app.KMinPeakHgtEditField_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.KMinPeakHgtEditField_3.ValueChangedFcn = createCallbackFcn(app, @KMinPeakHgtEditField_3ValueChanged, true);
            app.BtKEndEditField_3Label = uilabel(app.GridLayout5_3);
            app.BtKEndEditField_3Label.Text = '% Bt KEnd';
            app.BtKEndEditField_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.BtKEndEditField_3.ValueChangedFcn = createCallbackFcn(app, @BtKEndEditField_3ValueChanged, true);
            app.JStartEditField_3Label = uilabel(app.GridLayout5_3);
            app.JStartEditField_3Label.Text = 'JStart';
            app.JStartEditField_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.JStartEditField_3.ValueChangedFcn = createCallbackFcn(app, @JStartEditField_3ValueChanged, true);
            app.Label32_2 = uilabel(app.GridLayout5_3);
            app.Label32_2.Text = 'Label32';
            app.Label33_2 = uilabel(app.GridLayout5_3);
            app.Label33_2.Text = 'Label33';
            app.Label34_2 = uilabel(app.GridLayout5_3);
            app.Label34_2.Text = 'Label34';
            app.Label35_2 = uilabel(app.GridLayout5_3);
            app.Label35_2.Text = 'Label35';
            app.Label36_2 = uilabel(app.GridLayout5_3);
            app.Label36_2.Text = 'Label36';
            app.JMinPromEditField_3Label = uilabel(app.GridLayout5_3);
            app.JMinPromEditField_3Label.Text = 'J Min Prom';
            app.JMinPromEditField_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.JMinPromEditField_3.ValueChangedFcn = createCallbackFcn(app, @JMinPromEditField_3ValueChanged, true);
            app.IStartEditField_3Label = uilabel(app.GridLayout5_3);
            app.IStartEditField_3Label.Text = 'IStart';
            app.IStartEditField_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.IStartEditField_3.ValueChangedFcn = createCallbackFcn(app, @IStartEditField_3ValueChanged, true);
            app.HStartEditField_3Label = uilabel(app.GridLayout5_3);
            app.HStartEditField_3Label.Text = 'HStart';
            app.HStartEditField_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.HStartEditField_3.ValueChangedFcn = createCallbackFcn(app, @HStartEditField_3ValueChanged, true);
            app.HMinPeakHgtEditField_3Label = uilabel(app.GridLayout5_3);
            app.HMinPeakHgtEditField_3Label.Text = 'HMinPeakHgt';
            app.HMinPeakHgtEditField_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.HMinPeakHgtEditField_3.ValueChangedFcn = createCallbackFcn(app, @HMinPeakHgtEditField_3ValueChanged, true);
            app.LMinPeakHgtEditField_3Label = uilabel(app.GridLayout5_3);
            app.LMinPeakHgtEditField_3Label.Text = 'LMinPeakHgt';
            app.LMinPeakHgtEditField_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.LMinPeakHgtEditField_3.ValueChangedFcn = createCallbackFcn(app, @LMinPeakHgtEditField_3ValueChanged, true);
            app.LEndEditField_3Label = uilabel(app.GridLayout5_3);
            app.LEndEditField_3Label.Text = 'LEnd';
            app.LEndEditField_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.LEndEditField_3.ValueChangedFcn = createCallbackFcn(app, @LEndEditField_3ValueChanged, true);
            app.Label36_9 = uilabel(app.GridLayout5_3);
            app.Label36_9.Text = 'Label36';
            app.Label36_10 = uilabel(app.GridLayout5_3);
            app.Label36_10.Text = 'Label36';
            app.Label36_11 = uilabel(app.GridLayout5_3);
            app.Label36_11.Text = 'Label36';
            app.Label36_12 = uilabel(app.GridLayout5_3);
            app.Label36_12.Text = 'Label36';
            app.Label36_13 = uilabel(app.GridLayout5_3);
            app.Label36_13.Text = 'Label36';
            app.Label36_14 = uilabel(app.GridLayout5_3);
            app.Label36_14.Text = 'Label36';
            app.RBCG_DTab = uitab(app.TabGroup);
            app.RBCG_DTab.Title = 'RBCG_D';
            app.GridLayout5_4 = uigridlayout(app.RBCG_DTab);
            app.IMinPeakHgtEditField_4Label = uilabel(app.GridLayout5_4);
            app.IMinPeakHgtEditField_4Label.Text = 'IMinPeakHgt';
            app.IMinPeakHgtEditField_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.IMinPeakHgtEditField_4.ValueChangedFcn = createCallbackFcn(app, @IMinPeakHgtEditField_4ValueChanged, true);
            app.Label41_2 = uilabel(app.GridLayout5_4);
            app.Label41_2.Text = 'Label31';
            app.JMinPeakHgtEditField_4Label = uilabel(app.GridLayout5_4);
            app.JMinPeakHgtEditField_4Label.Text = 'JMinPeakHgt';
            app.JMinPeakHgtEditField_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.JMinPeakHgtEditField_4.ValueChangedFcn = createCallbackFcn(app, @JMinPeakHgtEditField_4ValueChanged, true);
            app.BtJEndEditField_4Label = uilabel(app.GridLayout5_4);
            app.BtJEndEditField_4Label.Text = '% Bt JEnd';
            app.BtJEndEditField_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.BtJEndEditField_4.ValueChangedFcn = createCallbackFcn(app, @BtJEndEditField_4ValueChanged, true);
            app.KMinPeakHgtEditField_4Label = uilabel(app.GridLayout5_4);
            app.KMinPeakHgtEditField_4Label.Text = 'KMinPeakHgt';
            app.KMinPeakHgtEditField_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.KMinPeakHgtEditField_4.ValueChangedFcn = createCallbackFcn(app, @KMinPeakHgtEditField_4ValueChanged, true);
            app.BtKEndEditField_4Label = uilabel(app.GridLayout5_4);
            app.BtKEndEditField_4Label.Text = '% Bt KEnd';
            app.BtKEndEditField_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.BtKEndEditField_4.ValueChangedFcn = createCallbackFcn(app, @BtKEndEditField_4ValueChanged, true);
            app.JStartEditField_4Label = uilabel(app.GridLayout5_4);
            app.JStartEditField_4Label.Text = 'JStart';
            app.JStartEditField_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.JStartEditField_4.ValueChangedFcn = createCallbackFcn(app, @JStartEditField_4ValueChanged, true);
            app.Label42_2 = uilabel(app.GridLayout5_4);
            app.Label42_2.Text = 'Label32';
            app.Label43_2 = uilabel(app.GridLayout5_4);
            app.Label43_2.Text = 'Label33';
            app.Label44_2 = uilabel(app.GridLayout5_4);
            app.Label44_2.Text = 'Label34';
            app.Label45_2 = uilabel(app.GridLayout5_4);
            app.Label45_2.Text = 'Label35';
            app.Label46_2 = uilabel(app.GridLayout5_4);
            app.Label46_2.Text = 'Label36';
            app.JMinPromEditField_4Label = uilabel(app.GridLayout5_4);
            app.JMinPromEditField_4Label.Text = 'J Min Prom';
            app.JMinPromEditField_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.JMinPromEditField_4.ValueChangedFcn = createCallbackFcn(app, @JMinPromEditField_4ValueChanged, true);
            app.IStartEditField_4Label = uilabel(app.GridLayout5_4);
            app.IStartEditField_4Label.Text = 'IStart';
            app.IStartEditField_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.IStartEditField_4.ValueChangedFcn = createCallbackFcn(app, @IStartEditField_4ValueChanged, true);
            app.HStartEditField_4Label = uilabel(app.GridLayout5_4);
            app.HStartEditField_4Label.Text = 'HStart';
            app.HStartEditField_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.HStartEditField_4.ValueChangedFcn = createCallbackFcn(app, @HStartEditField_4ValueChanged, true);
            app.HMinPeakHgtEditField_4Label = uilabel(app.GridLayout5_4);
            app.HMinPeakHgtEditField_4Label.Text = 'HMinPeakHgt';
            app.HMinPeakHgtEditField_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.HMinPeakHgtEditField_4.ValueChangedFcn = createCallbackFcn(app, @HMinPeakHgtEditField_4ValueChanged, true);
            app.LMinPeakHgtEditField_4Label = uilabel(app.GridLayout5_4);
            app.LMinPeakHgtEditField_4Label.Text = 'LMinPeakHgt';
            app.LMinPeakHgtEditField_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.LMinPeakHgtEditField_4.ValueChangedFcn = createCallbackFcn(app, @LMinPeakHgtEditField_4ValueChanged, true);
            app.LEndEditField_4Label = uilabel(app.GridLayout5_4);
            app.LEndEditField_4Label.Text = 'LEnd';
            app.LEndEditField_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.LEndEditField_4.ValueChangedFcn = createCallbackFcn(app, @LEndEditField_4ValueChanged, true);
            app.Label46_9 = uilabel(app.GridLayout5_4);
            app.Label46_9.Text = 'Label36';
            app.Label46_10 = uilabel(app.GridLayout5_4);
            app.Label46_10.Text = 'Label36';
            app.Label46_11 = uilabel(app.GridLayout5_4);
            app.Label46_11.Text = 'Label36';
            app.Label46_12 = uilabel(app.GridLayout5_4);
            app.Label46_12.Text = 'Label36';
            app.Label46_13 = uilabel(app.GridLayout5_4);
            app.Label46_13.Text = 'Label36';
            app.Label46_14 = uilabel(app.GridLayout5_4);
            app.Label46_14.Text = 'Label36';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.AllFeaturesButton = uibutton(app.GridLayout6, 'push');
            app.AllFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @AllFeaturesButtonPushed, true);
            app.AllFeaturesButton.Text = 'All Features';
            app.MinMaxWinButton = uibutton(app.GridLayout6, 'push');
            app.MinMaxWinButton.Text = 'Min/Max Win';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.SaveButton = uibutton(app.GridLayout7, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RemoveButton = uibutton(app.GridLayout7, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.WinFeatureButton = uibutton(app.GridLayout7, 'push');
            app.WinFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @WinFeatureButtonPushed, true);
            app.WinFeatureButton.Text = 'Win Feature';
            app.GridLayout12 = uigridlayout(app.GridLayout);
            app.GridLayout14 = uigridlayout(app.GridLayout12);
            app.HPfrEditFieldLabel = uilabel(app.GridLayout14);
            app.HPfrEditFieldLabel.Text = 'HP fr';
            app.HPfrEditField = uieditfield(app.GridLayout14, 'numeric');
            app.HPfrEditField.ValueChangedFcn = createCallbackFcn(app, @HPfrEditFieldValueChanged, true);
            app.DefaultLabel = uilabel(app.GridLayout14);
            app.DefaultLabel.Text = 'Default';
            app.Label37 = uilabel(app.GridLayout14);
            app.Label37.Text = 'Label37';
            app.GridLayout15 = uigridlayout(app.GridLayout12);
            app.HPfrIntEditFieldLabel = uilabel(app.GridLayout15);
            app.HPfrIntEditFieldLabel.Text = 'HP fr - Int';
            app.HPfrIntEditField = uieditfield(app.GridLayout15, 'numeric');
            app.HPfrIntEditField.ValueChangedFcn = createCallbackFcn(app, @HPfrIntEditFieldValueChanged, true);
            app.DefaultLabel_2 = uilabel(app.GridLayout15);
            app.DefaultLabel_2.Text = 'Default';
            app.Label38 = uilabel(app.GridLayout15);
            app.Label38.Text = 'Label38';
            app.FeaturesTab = uitab(app.TabGroup2);
            app.FeaturesTab.Title = 'Features';
            app.GridLayout8 = uigridlayout(app.FeaturesTab);
            app.UIAxes1_2 = uiaxes(app.GridLayout8);
            title(app.UIAxes1_2, '')
            xlabel(app.UIAxes1_2, '')
            ylabel(app.UIAxes1_2, 'ECG_t')
            app.UIAxes2_2 = uiaxes(app.GridLayout8);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, 'ECG_b')
            app.UIAxes3_2 = uiaxes(app.GridLayout8);
            title(app.UIAxes3_2, '')
            xlabel(app.UIAxes3_2, '')
            ylabel(app.UIAxes3_2, 'BP_1')
            app.UIAxes4_2 = uiaxes(app.GridLayout8);
            title(app.UIAxes4_2, '')
            xlabel(app.UIAxes4_2, '')
            ylabel(app.UIAxes4_2, 'BP_2')
            app.UIAxes5_2 = uiaxes(app.GridLayout8);
            title(app.UIAxes5_2, '')
            xlabel(app.UIAxes5_2, '')
            ylabel(app.UIAxes5_2, 'BCG Acc')
            app.UIAxes6_2 = uiaxes(app.GridLayout8);
            title(app.UIAxes6_2, '')
            xlabel(app.UIAxes6_2, '')
            ylabel(app.UIAxes6_2, 'RotBCG Acc')
            app.UIAxes7_2 = uiaxes(app.GridLayout8);
            title(app.UIAxes7_2, '')
            xlabel(app.UIAxes7_2, '')
            ylabel(app.UIAxes7_2, 'BCG Disp')
            app.UIAxes8_2 = uiaxes(app.GridLayout8);
            title(app.UIAxes8_2, '')
            xlabel(app.UIAxes8_2, '')
            ylabel(app.UIAxes8_2, 'RotBCG Disp')
            app.UIAxes8_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.MovWindowSpinnerLabel = uilabel(app.GridLayout9);
            app.MovWindowSpinnerLabel.Text = 'Mov Window';
            app.MovWindowSpinner = uispinner(app.GridLayout9);
            app.MovWindowSpinner.ValueChangedFcn = createCallbackFcn(app, @MovWindowSpinnerValueChanged, true);
            app.MovWindowSpinner.Value = 10;
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.MovAveButton = uibutton(app.GridLayout10, 'push');
            app.MovAveButton.ButtonPushedFcn = createCallbackFcn(app, @MovAveButtonPushed, true);
            app.MovAveButton.Text = 'Mov Ave';
            app.MinMaxWinButton_2 = uibutton(app.GridLayout10, 'push');
            app.MinMaxWinButton_2.ButtonPushedFcn = createCallbackFcn(app, @MinMaxWinButton_2Pushed, true);
            app.MinMaxWinButton_2.Text = 'Min/Max Win';
            app.PlotButton = uibutton(app.GridLayout10, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GridLayout19 = uigridlayout(app.GridLayout8);
            app.Slider1_2 = uislider(app.GridLayout19);
            app.Slider1_2.MajorTickLabels = {''};
            app.Slider1_2.ValueChangedFcn = createCallbackFcn(app, @Slider1_2ValueChanged, true);
            app.Slider2_2 = uislider(app.GridLayout19);
            app.Slider2_2.MajorTickLabels = {''};
            app.Slider2_2.ValueChangedFcn = createCallbackFcn(app, @Slider2_2ValueChanged, true);
            app.RepTab = uitab(app.TabGroup2);
            app.RepTab.Title = 'Rep';
            app.GridLayout11 = uigridlayout(app.RepTab);
            app.UIAxes1_Rep = uiaxes(app.GridLayout11);
            title(app.UIAxes1_Rep, '')
            xlabel(app.UIAxes1_Rep, '')
            ylabel(app.UIAxes1_Rep, 'BP_1')
            app.UIAxes2_Rep = uiaxes(app.GridLayout11);
            title(app.UIAxes2_Rep, '')
            xlabel(app.UIAxes2_Rep, '')
            ylabel(app.UIAxes2_Rep, 'BP_2')
            app.UIAxes3_Rep = uiaxes(app.GridLayout11);
            title(app.UIAxes3_Rep, '')
            xlabel(app.UIAxes3_Rep, '')
            ylabel(app.UIAxes3_Rep, 'BCG A')
            app.UIAxes4_Rep = uiaxes(app.GridLayout11);
            title(app.UIAxes4_Rep, '')
            xlabel(app.UIAxes4_Rep, '')
            ylabel(app.UIAxes4_Rep, 'rotBCG A')
            app.UIAxes5_Rep = uiaxes(app.GridLayout11);
            title(app.UIAxes5_Rep, '')
            xlabel(app.UIAxes5_Rep, '')
            ylabel(app.UIAxes5_Rep, 'BCG D')
            app.UIAxes6_Rep = uiaxes(app.GridLayout11);
            title(app.UIAxes6_Rep, '')
            xlabel(app.UIAxes6_Rep, '')
            ylabel(app.UIAxes6_Rep, 'rotBCG D')
            app.GridLayout18 = uigridlayout(app.GridLayout11);
            app.PlotButton_2 = uibutton(app.GridLayout18, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @RepresentativeButtonPushed, true);
            app.PlotButton_2.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_20200802_app2_Tab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end